classdef optocontrol_v4point1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptogeneticsControlLiangLabUIFigure  matlab.ui.Figure
        ConfigMenu                     matlab.ui.container.Menu
        SaveConfigMenu                 matlab.ui.container.Menu
        LoadConfigMenu                 matlab.ui.container.Menu
        HelpButton                     matlab.ui.control.Button
        OutputTextArea                 matlab.ui.control.TextArea
        OutputTextAreaLabel            matlab.ui.control.Label
        PrePeriodEditField             matlab.ui.control.EditField
        PrePeriodEditFieldLabel        matlab.ui.control.Label
        unitsLabel                     matlab.ui.control.Label
        PostPeriodEditField            matlab.ui.control.EditField
        PostPeriodEditField_2Label     matlab.ui.control.Label
        unitsLabel_2                   matlab.ui.control.Label
        StimulationPeriodPanel         matlab.ui.container.Panel
        DurationunitsLabel             matlab.ui.control.Label
        DurationEditField_4            matlab.ui.control.EditField
        DurationEditField_4Label       matlab.ui.control.Label
        DurationEditField_3            matlab.ui.control.EditField
        DurationEditField_3Label       matlab.ui.control.Label
        DurationEditField_2            matlab.ui.control.EditField
        DurationEditField_2Label       matlab.ui.control.Label
        DurationEditField_1            matlab.ui.control.EditField
        DurationEditFieldLabel         matlab.ui.control.Label
        DutyRatiounitLabel             matlab.ui.control.Label
        FrequnitHzLabel                matlab.ui.control.Label
        Stage4Switch                   matlab.ui.control.Switch
        Stage4SwitchLabel              matlab.ui.control.Label
        BlueDutyRatioEditField_4       matlab.ui.control.EditField
        BlueDutyRatioEditField_4Label  matlab.ui.control.Label
        BlueFreqEditField_4            matlab.ui.control.EditField
        BlueFreqEditField_4Label       matlab.ui.control.Label
        RedFreqEditField_4             matlab.ui.control.EditField
        RedFreqEditField_4Label        matlab.ui.control.Label
        RedDutyRatioEditField_4        matlab.ui.control.EditField
        RedDutyRatioEditField_4Label   matlab.ui.control.Label
        Stage3Switch                   matlab.ui.control.Switch
        Stage3SwitchLabel              matlab.ui.control.Label
        BlueDutyRatioEditField_3       matlab.ui.control.EditField
        BlueDutyRatioEditField_3Label  matlab.ui.control.Label
        BlueFreqEditField_3            matlab.ui.control.EditField
        BlueFreqEditField_3Label       matlab.ui.control.Label
        RedFreqEditField_3             matlab.ui.control.EditField
        RedFreqEditField_3Label        matlab.ui.control.Label
        RedDutyRatioEditField_3        matlab.ui.control.EditField
        RedDutyRatioEditField_3Label   matlab.ui.control.Label
        Stage2Switch                   matlab.ui.control.Switch
        Stage2SwitchLabel              matlab.ui.control.Label
        BlueDutyRatioEditField_2       matlab.ui.control.EditField
        BlueDutyRatioEditField_2Label  matlab.ui.control.Label
        BlueFreqEditField_2            matlab.ui.control.EditField
        BlueFreqEditField_2Label       matlab.ui.control.Label
        RedFreqEditField_2             matlab.ui.control.EditField
        RedFreqEditField_2Label        matlab.ui.control.Label
        RedDutyRatioEditField_2        matlab.ui.control.EditField
        RedDutyRatioEditField_2Label   matlab.ui.control.Label
        BlueDutyRatioEditField_1       matlab.ui.control.EditField
        BlueDutyRatioEditFieldLabel    matlab.ui.control.Label
        BlueFreqEditField_1            matlab.ui.control.EditField
        BlueFreqEditFieldLabel         matlab.ui.control.Label
        RedFreqEditField_1             matlab.ui.control.EditField
        RedFreqEditFieldLabel          matlab.ui.control.Label
        RedDutyRatioEditField_1        matlab.ui.control.EditField
        RedDutyRatioEditFieldLabel     matlab.ui.control.Label
        Stage1Switch                   matlab.ui.control.Switch
        Stage1SwitchLabel              matlab.ui.control.Label
        CycleNumberEditField           matlab.ui.control.EditField
        CycleNumberEditFieldLabel      matlab.ui.control.Label
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
        pre_period;
        cycle_number;
        stage1_duration;
        stage1_red_freq;
        stage1_red_duty_ratio;
        stage1_blue_freq;
        stage1_blue_duty_ratio;
        stage2_duration;
        stage2_red_freq;
        stage2_red_duty_ratio;
        stage2_blue_freq;
        stage2_blue_duty_ratio;
        stage3_duration;
        stage3_red_freq;
        stage3_red_duty_ratio;
        stage3_blue_freq;
        stage3_blue_duty_ratio;
        stage4_duration;
        stage4_red_freq;
        stage4_red_duty_ratio;
        stage4_blue_freq;
        stage4_blue_duty_ratio;
        post_period;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.pre_period = 120;
%             app.cycle_number = 20;
%             app.stage1_duration = 2;
%             app.stage1_red_freq = 20;
%             app.stage1_red_duty_ratio = 20;
%             app.stage1_blue_freq = 1;
%             app.stage1_blue_duty_ratio = 0;
%             app.stage2_duration = 4;
%             app.stage2_red_freq = 20;
%             app.stage2_red_duty_ratio = 20;
%             app.stage2_blue_freq = 1;
%             app.stage2_blue_duty_ratio = 100;
%             app.stage3_duration = 1;
%             app.stage3_red_freq = 1;
%             app.stage3_red_duty_ratio = 0;
%             app.stage3_blue_freq = 1;
%             app.stage3_blue_duty_ratio = 100;
%             app.stage4_duration = 23;
%             app.stage4_red_freq = 1;
%             app.stage4_red_duty_ratio = 0;
%             app.stage4_blue_freq = 1;
%             app.stage4_blue_duty_ratio = 0;
%             app.post_period = 120;
            app.pre_period = str2double(app.PrePeriodEditField.Value);
            app.cycle_number = str2double(app.CycleNumberEditField.Value);
            app.stage1_duration = str2double(app.DurationEditField_1.Value);
            app.stage1_red_freq = str2double(app.RedFreqEditField_1.Value);
            app.stage1_red_duty_ratio = str2double(app.RedDutyRatioEditField_1.Value);
            app.stage1_blue_freq = str2double(app.BlueFreqEditField_1.Value);
            app.stage1_blue_duty_ratio = str2double(app.BlueDutyRatioEditField_1.Value);
            app.stage2_duration = str2double(app.DurationEditField_2.Value);
            app.stage2_red_freq = str2double(app.RedFreqEditField_2.Value);
            app.stage2_red_duty_ratio = str2double(app.RedDutyRatioEditField_2.Value);
            app.stage2_blue_freq = str2double(app.BlueFreqEditField_2.Value);
            app.stage2_blue_duty_ratio = str2double(app.BlueDutyRatioEditField_2.Value);
            app.stage3_duration = str2double(app.DurationEditField_3.Value);
            app.stage3_red_freq = str2double(app.RedFreqEditField_3.Value);
            app.stage3_red_duty_ratio = str2double(app.RedDutyRatioEditField_3.Value);
            app.stage3_blue_freq = str2double(app.BlueFreqEditField_3.Value);
            app.stage3_blue_duty_ratio = str2double(app.BlueDutyRatioEditField_3.Value);
            app.stage4_duration = str2double(app.DurationEditField_4.Value);
            app.stage4_red_freq = str2double(app.RedFreqEditField_4.Value);
            app.stage4_red_duty_ratio = str2double(app.RedDutyRatioEditField_4.Value);
            app.stage4_blue_freq = str2double(app.BlueFreqEditField_4.Value);
            app.stage4_blue_duty_ratio = str2double(app.BlueDutyRatioEditField_4.Value);
            app.post_period = str2double(app.PostPeriodEditField.Value);
            app.OutputTextArea.Value = "Optogenetics Control Output Window";

            value = app.Stage1Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.RedFreqEditField_1.Enable = "off";
                app.RedDutyRatioEditField_1.Enable = "off";
                app.BlueFreqEditField_1.Enable = "off";
                app.BlueDutyRatioEditField_1.Enable = "off";
                app.DurationEditField_1.Enable = "off";
            elseif value == "On"
                app.RedFreqEditField_1.Enable = "on";
                app.RedDutyRatioEditField_1.Enable = "on";
                app.BlueFreqEditField_1.Enable = "on";
                app.BlueDutyRatioEditField_1.Enable = "on";
                app.DurationEditField_1.Enable = "on";
            end

            value = app.Stage2Switch.Value;
            if value == "Off"
                app.RedFreqEditField_2.Enable = "off";
                app.RedDutyRatioEditField_2.Enable = "off";
                app.BlueFreqEditField_2.Enable = "off";
                app.BlueDutyRatioEditField_2.Enable = "off";
                app.DurationEditField_2.Enable = "off";
            elseif value == "On"
                app.RedFreqEditField_2.Enable = "on";
                app.RedDutyRatioEditField_2.Enable = "on";
                app.BlueFreqEditField_2.Enable = "on";
                app.BlueDutyRatioEditField_2.Enable = "on";
                app.DurationEditField_2.Enable = "on";
            end

            value = app.Stage3Switch.Value;
            if value == "Off"
                app.RedFreqEditField_3.Enable = "off";
                app.RedDutyRatioEditField_3.Enable = "off";
                app.BlueFreqEditField_3.Enable = "off";
                app.BlueDutyRatioEditField_3.Enable = "off";
                app.DurationEditField_3.Enable = "off";
            elseif value == "On"
                app.RedFreqEditField_3.Enable = "on";
                app.RedDutyRatioEditField_3.Enable = "on";
                app.BlueFreqEditField_3.Enable = "on";
                app.BlueDutyRatioEditField_3.Enable = "on";
                app.DurationEditField_3.Enable = "on";
            end

            value = app.Stage4Switch.Value;
            if value == "Off"
                app.RedFreqEditField_4.Enable = "off";
                app.RedDutyRatioEditField_4.Enable = "off";
                app.BlueFreqEditField_4.Enable = "off";
                app.BlueDutyRatioEditField_4.Enable = "off";
                app.DurationEditField_4.Enable = "off";
            elseif value == "On"
                app.RedFreqEditField_4.Enable = "on";
                app.RedDutyRatioEditField_4.Enable = "on";
                app.BlueFreqEditField_4.Enable = "on";
                app.BlueDutyRatioEditField_4.Enable = "on";
                app.DurationEditField_4.Enable = "on";
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = "off";
            disp_info = "Experiment begins!";
            disp(disp_info);
            app.OutputTextArea.Value = [app.OutputTextArea.Value; disp_info];
            app.OutputTextArea.scroll('bottom');
            pause(2);
            Optogenetics_func_for_App(app);
            pause(2);
            disp_info = "Experiment ends!";
            disp(disp_info);
            app.OutputTextArea.Value = [app.OutputTextArea.Value; disp_info];
            app.OutputTextArea.scroll('bottom');
            app.StartButton.Enable = "on";
        end

        % Value changed function: PrePeriodEditField
        function PrePeriodEditFieldValueChanged(app, event)
            value = app.PrePeriodEditField.Value;
            app.pre_period = str2double(value);
        end

        % Value changed function: CycleNumberEditField
        function CycleNumberEditFieldValueChanged(app, event)
            value = app.CycleNumberEditField.Value;
            app.cycle_number = str2double(value);
        end

        % Value changed function: RedFreqEditField_1
        function RedFreqEditField_1ValueChanged(app, event)
            value = app.RedFreqEditField_1.Value;
            app.stage1_red_freq = str2double(value);
        end

        % Value changed function: RedDutyRatioEditField_1
        function RedDutyRatioEditField_1ValueChanged(app, event)
            value = app.RedDutyRatioEditField_1.Value;
            app.stage1_red_duty_ratio = str2double(value);
        end

        % Value changed function: BlueFreqEditField_1
        function BlueFreqEditField_1ValueChanged(app, event)
            value = app.BlueFreqEditField_1.Value;
            app.stage1_blue_freq = str2double(value);
        end

        % Value changed function: BlueDutyRatioEditField_1
        function BlueDutyRatioEditField_1ValueChanged(app, event)
            value = app.BlueDutyRatioEditField_1.Value;
            app.stage1_blue_duty_ratio = str2double(value);
        end

        % Value changed function: Stage1Switch
        function Stage1SwitchValueChanged(app, event)
            value = app.Stage1Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.RedFreqEditField_1.Enable = "off";
                app.RedDutyRatioEditField_1.Enable = "off";
                app.BlueFreqEditField_1.Enable = "off";
                app.BlueDutyRatioEditField_1.Enable = "off";
                app.DurationEditField_1.Enable = "off";
            elseif value == "On"
                app.RedFreqEditField_1.Enable = "on";
                app.RedDutyRatioEditField_1.Enable = "on";
                app.BlueFreqEditField_1.Enable = "on";
                app.BlueDutyRatioEditField_1.Enable = "on";
                app.DurationEditField_1.Enable = "on";
            end
        end

        % Value changed function: RedFreqEditField_2
        function RedFreqEditField_2ValueChanged(app, event)
            value = app.RedFreqEditField_2.Value;
            app.stage2_red_freq = str2double(value);
        end

        % Value changed function: RedDutyRatioEditField_2
        function RedDutyRatioEditField_2ValueChanged(app, event)
            value = app.RedDutyRatioEditField_2.Value;
            app.stage2_red_duty_ratio = str2double(value);
        end

        % Value changed function: BlueFreqEditField_2
        function BlueFreqEditField_2ValueChanged(app, event)
            value = app.BlueFreqEditField_2.Value;
            app.stage2_blue_freq = str2double(value);
        end

        % Value changed function: BlueDutyRatioEditField_2
        function BlueDutyRatioEditField_2ValueChanged(app, event)
            value = app.BlueDutyRatioEditField_2.Value;
            app.stage2_blue_duty_ratio = str2double(value);
        end

        % Value changed function: Stage2Switch
        function Stage2SwitchValueChanged(app, event)
            value = app.Stage2Switch.Value;
            if value == "Off"
                app.RedFreqEditField_2.Enable = "off";
                app.RedDutyRatioEditField_2.Enable = "off";
                app.BlueFreqEditField_2.Enable = "off";
                app.BlueDutyRatioEditField_2.Enable = "off";
                app.DurationEditField_2.Enable = "off";
            elseif value == "On"
                app.RedFreqEditField_2.Enable = "on";
                app.RedDutyRatioEditField_2.Enable = "on";
                app.BlueFreqEditField_2.Enable = "on";
                app.BlueDutyRatioEditField_2.Enable = "on";
                app.DurationEditField_2.Enable = "on";
            end
        end

        % Value changed function: RedFreqEditField_3
        function RedFreqEditField_3ValueChanged(app, event)
            value = app.RedFreqEditField_3.Value;
            app.stage3_red_freq = str2double(value);
        end

        % Value changed function: RedDutyRatioEditField_3
        function RedDutyRatioEditField_3ValueChanged(app, event)
            value = app.RedDutyRatioEditField_3.Value;
            app.stage3_red_duty_ratio = str2double(value);
        end

        % Value changed function: BlueFreqEditField_3
        function BlueFreqEditField_3ValueChanged(app, event)
            value = app.BlueFreqEditField_3.Value;
            app.stage3_blue_freq = str2double(value);
        end

        % Value changed function: BlueDutyRatioEditField_3
        function BlueDutyRatioEditField_3ValueChanged(app, event)
            value = app.BlueDutyRatioEditField_3.Value;
            app.stage3_blue_duty_ratio = str2double(value);
        end

        % Value changed function: Stage3Switch
        function Stage3SwitchValueChanged(app, event)
            value = app.Stage3Switch.Value;
            if value == "Off"
                app.RedFreqEditField_3.Enable = "off";
                app.RedDutyRatioEditField_3.Enable = "off";
                app.BlueFreqEditField_3.Enable = "off";
                app.BlueDutyRatioEditField_3.Enable = "off";
                app.DurationEditField_3.Enable = "off";
            elseif value == "On"
                app.RedFreqEditField_3.Enable = "on";
                app.RedDutyRatioEditField_3.Enable = "on";
                app.BlueFreqEditField_3.Enable = "on";
                app.BlueDutyRatioEditField_3.Enable = "on";
                app.DurationEditField_3.Enable = "on";
            end
        end

        % Value changed function: RedFreqEditField_4
        function RedFreqEditField_4ValueChanged(app, event)
            value = app.RedFreqEditField_4.Value;
            app.stage4_red_freq = str2double(value);
        end

        % Value changed function: RedDutyRatioEditField_4
        function RedDutyRatioEditField_4ValueChanged(app, event)
            value = app.RedDutyRatioEditField_4.Value;
            app.stage4_red_duty_ratio = str2double(value);
        end

        % Value changed function: BlueFreqEditField_4
        function BlueFreqEditField_4ValueChanged(app, event)
            value = app.BlueFreqEditField_4.Value;
            app.stage4_blue_freq = str2double(value);
        end

        % Value changed function: BlueDutyRatioEditField_4
        function BlueDutyRatioEditField_4ValueChanged(app, event)
            value = app.BlueDutyRatioEditField_4.Value;
            app.stage4_blue_duty_ratio = str2double(value);
        end

        % Value changed function: Stage4Switch
        function Stage4SwitchValueChanged(app, event)
            value = app.Stage4Switch.Value;
            if value == "Off"
                app.RedFreqEditField_4.Enable = "off";
                app.RedDutyRatioEditField_4.Enable = "off";
                app.BlueFreqEditField_4.Enable = "off";
                app.BlueDutyRatioEditField_4.Enable = "off";
                app.DurationEditField_4.Enable = "off";
            elseif value == "On"
                app.RedFreqEditField_4.Enable = "on";
                app.RedDutyRatioEditField_4.Enable = "on";
                app.BlueFreqEditField_4.Enable = "on";
                app.BlueDutyRatioEditField_4.Enable = "on";
                app.DurationEditField_4.Enable = "on";
            end
        end

        % Value changed function: PostPeriodEditField
        function PostPeriodEditFieldValueChanged(app, event)
            value = app.PostPeriodEditField.Value;
            app.post_period = str2double(value);
        end

        % Value changed function: DurationEditField_1
        function DurationEditField_1ValueChanged(app, event)
            value = app.DurationEditField_1.Value;
            app.stage1_duration = str2double(value);
        end

        % Value changed function: DurationEditField_2
        function DurationEditField_2ValueChanged(app, event)
            value = app.DurationEditField_2.Value;
            app.stage2_duration = str2double(value);
        end

        % Value changed function: DurationEditField_3
        function DurationEditField_3ValueChanged(app, event)
            value = app.DurationEditField_3.Value;
            app.stage3_duration = str2double(value);
        end

        % Value changed function: DurationEditField_4
        function DurationEditField_4ValueChanged(app, event)
            value = app.DurationEditField_4.Value;
            app.stage4_duration = str2double(value);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            optocontrol_help;
        end

        % Menu selected function: SaveConfigMenu
        function SaveConfigMenuSelected(app, event)
            config.pre_period = app.pre_period; % unit:s
            config.cycle_number = app.cycle_number;
            config.post_period = app.post_period; % unit:s
            
            config.stage1_switch = app.Stage1Switch.Value; % 'On' or 'Off'
            config.stage1_duration = app.stage1_duration; % unit: s
            config.stage1_red_freq = app.stage1_red_freq; % unit:Hz
            config.stage1_red_duty_ratio = app.stage1_red_duty_ratio; % unit:%
            config.stage1_blue_freq = app.stage1_blue_freq;
            config.stage1_blue_duty_ratio = app.stage1_blue_duty_ratio;
            
            config.stage2_switch = app.Stage2Switch.Value;
            config.stage2_duration = app.stage2_duration;
            config.stage2_red_freq = app.stage2_red_freq;
            config.stage2_red_duty_ratio = app.stage2_red_duty_ratio;
            config.stage2_blue_freq = app.stage2_blue_freq;
            config.stage2_blue_duty_ratio = app.stage2_blue_duty_ratio;
            
            config.stage3_switch = app.Stage3Switch.Value;
            config.stage3_duration = app.stage3_duration;
            config.stage3_red_freq = app.stage3_red_freq;
            config.stage3_red_duty_ratio = app.stage3_red_duty_ratio;
            config.stage3_blue_freq = app.stage3_blue_freq;
            config.stage3_blue_duty_ratio = app.stage3_blue_duty_ratio;
            
            config.stage4_switch = app.Stage4Switch.Value;
            config.stage4_duration = app.stage4_duration;
            config.stage4_red_freq = app.stage4_red_freq;
            config.stage4_red_duty_ratio = app.stage4_red_duty_ratio;
            config.stage4_blue_freq = app.stage4_blue_freq;
            config.stage4_blue_duty_ratio = app.stage4_blue_duty_ratio;

            % Open the file save dialog
            [fileName, filePath] = uiputfile('*.json', 'Save JSON File');
        
            % Check if the user selected a file name and path
            if fileName
                % Create the full file path
                fullFilePath = fullfile(filePath, fileName);
        
                % Save files using the specified file path
                jsonStr = jsonencode(config);
                fid = fopen(fullFilePath, 'w');
                fwrite(fid, jsonStr);
                fclose(fid);               
            end
        end

        % Menu selected function: LoadConfigMenu
        function LoadConfigMenuSelected(app, event)
            % Open the file selection dialog
            [fileName, filePath] = uigetfile('*.json', 'Select JSON File');
        
            % Check if the user selected a file
            if fileName
                % Create the full file path
                fullFilePath = fullfile(filePath, fileName);
        
                % Read data from the selected file
                fid = fopen(fullFilePath, 'r');
                jsonStr = fread(fid, '*char')';
                fclose(fid);
        
                % Parse JSON data into a struct
                config = jsondecode(jsonStr);
                
                app.PrePeriodEditField.Value = num2str(config.pre_period); % unit:s
                app.CycleNumberEditField.Value = num2str(config.cycle_number);
                app.PostPeriodEditField.Value = num2str(config.post_period); % unit:s
                
                app.Stage1Switch.Value = config.stage1_switch; % 'On' or 'Off'
                app.DurationEditField_1.Value = num2str(config.stage1_duration); % unit: s
                app.RedFreqEditField_1.Value = num2str(config.stage1_red_freq); % unit:Hz
                app.RedDutyRatioEditField_1.Value = num2str(config.stage1_red_duty_ratio); % unit:%
                app.BlueFreqEditField_1.Value = num2str(config.stage1_blue_freq);
                app.BlueDutyRatioEditField_1.Value = num2str(config.stage1_blue_duty_ratio);
                
                app.Stage2Switch.Value = config.stage2_switch;
                app.DurationEditField_2.Value = num2str(config.stage2_duration);
                app.RedFreqEditField_2.Value = num2str(config.stage2_red_freq);
                app.RedDutyRatioEditField_2.Value = num2str(config.stage2_red_duty_ratio);
                app.BlueFreqEditField_2.Value = num2str(config.stage2_blue_freq);
                app.BlueDutyRatioEditField_2.Value = num2str(config.stage2_blue_duty_ratio);
                
                app.Stage3Switch.Value = config.stage3_switch;
                app.DurationEditField_3.Value = num2str(config.stage3_duration);
                app.RedFreqEditField_3.Value = num2str(config.stage3_red_freq);
                app.RedDutyRatioEditField_3.Value = num2str(config.stage3_red_duty_ratio);
                app.BlueFreqEditField_3.Value = num2str(config.stage3_blue_freq);
                app.BlueDutyRatioEditField_3.Value = num2str(config.stage3_blue_duty_ratio);
                
                app.Stage4Switch.Value = config.stage4_switch;
                app.DurationEditField_4.Value = num2str(config.stage4_duration);
                app.RedFreqEditField_4.Value = num2str(config.stage4_red_freq);
                app.RedDutyRatioEditField_4.Value = num2str(config.stage4_red_duty_ratio);
                app.BlueFreqEditField_4.Value = num2str(config.stage4_blue_freq);
                app.BlueDutyRatioEditField_4.Value = num2str(config.stage4_blue_duty_ratio);

                app.pre_period = str2double(app.PrePeriodEditField.Value);
                app.cycle_number = str2double(app.CycleNumberEditField.Value);
                app.stage1_duration = str2double(app.DurationEditField_1.Value);
                app.stage1_red_freq = str2double(app.RedFreqEditField_1.Value);
                app.stage1_red_duty_ratio = str2double(app.RedDutyRatioEditField_1.Value);
                app.stage1_blue_freq = str2double(app.BlueFreqEditField_1.Value);
                app.stage1_blue_duty_ratio = str2double(app.BlueDutyRatioEditField_1.Value);
                app.stage2_duration = str2double(app.DurationEditField_2.Value);
                app.stage2_red_freq = str2double(app.RedFreqEditField_2.Value);
                app.stage2_red_duty_ratio = str2double(app.RedDutyRatioEditField_2.Value);
                app.stage2_blue_freq = str2double(app.BlueFreqEditField_2.Value);
                app.stage2_blue_duty_ratio = str2double(app.BlueDutyRatioEditField_2.Value);
                app.stage3_duration = str2double(app.DurationEditField_3.Value);
                app.stage3_red_freq = str2double(app.RedFreqEditField_3.Value);
                app.stage3_red_duty_ratio = str2double(app.RedDutyRatioEditField_3.Value);
                app.stage3_blue_freq = str2double(app.BlueFreqEditField_3.Value);
                app.stage3_blue_duty_ratio = str2double(app.BlueDutyRatioEditField_3.Value);
                app.stage4_duration = str2double(app.DurationEditField_4.Value);
                app.stage4_red_freq = str2double(app.RedFreqEditField_4.Value);
                app.stage4_red_duty_ratio = str2double(app.RedDutyRatioEditField_4.Value);
                app.stage4_blue_freq = str2double(app.BlueFreqEditField_4.Value);
                app.stage4_blue_duty_ratio = str2double(app.BlueDutyRatioEditField_4.Value);
                app.post_period = str2double(app.PostPeriodEditField.Value);

                value = app.Stage1Switch.Value;
                if value == "Off" % value is actually char, but char can be compared with string
                    app.RedFreqEditField_1.Enable = "off";
                    app.RedDutyRatioEditField_1.Enable = "off";
                    app.BlueFreqEditField_1.Enable = "off";
                    app.BlueDutyRatioEditField_1.Enable = "off";
                    app.DurationEditField_1.Enable = "off";
                elseif value == "On"
                    app.RedFreqEditField_1.Enable = "on";
                    app.RedDutyRatioEditField_1.Enable = "on";
                    app.BlueFreqEditField_1.Enable = "on";
                    app.BlueDutyRatioEditField_1.Enable = "on";
                    app.DurationEditField_1.Enable = "on";
                end
    
                value = app.Stage2Switch.Value;
                if value == "Off"
                    app.RedFreqEditField_2.Enable = "off";
                    app.RedDutyRatioEditField_2.Enable = "off";
                    app.BlueFreqEditField_2.Enable = "off";
                    app.BlueDutyRatioEditField_2.Enable = "off";
                    app.DurationEditField_2.Enable = "off";
                elseif value == "On"
                    app.RedFreqEditField_2.Enable = "on";
                    app.RedDutyRatioEditField_2.Enable = "on";
                    app.BlueFreqEditField_2.Enable = "on";
                    app.BlueDutyRatioEditField_2.Enable = "on";
                    app.DurationEditField_2.Enable = "on";
                end
    
                value = app.Stage3Switch.Value;
                if value == "Off"
                    app.RedFreqEditField_3.Enable = "off";
                    app.RedDutyRatioEditField_3.Enable = "off";
                    app.BlueFreqEditField_3.Enable = "off";
                    app.BlueDutyRatioEditField_3.Enable = "off";
                    app.DurationEditField_3.Enable = "off";
                elseif value == "On"
                    app.RedFreqEditField_3.Enable = "on";
                    app.RedDutyRatioEditField_3.Enable = "on";
                    app.BlueFreqEditField_3.Enable = "on";
                    app.BlueDutyRatioEditField_3.Enable = "on";
                    app.DurationEditField_3.Enable = "on";
                end
    
                value = app.Stage4Switch.Value;
                if value == "Off"
                    app.RedFreqEditField_4.Enable = "off";
                    app.RedDutyRatioEditField_4.Enable = "off";
                    app.BlueFreqEditField_4.Enable = "off";
                    app.BlueDutyRatioEditField_4.Enable = "off";
                    app.DurationEditField_4.Enable = "off";
                elseif value == "On"
                    app.RedFreqEditField_4.Enable = "on";
                    app.RedDutyRatioEditField_4.Enable = "on";
                    app.BlueFreqEditField_4.Enable = "on";
                    app.BlueDutyRatioEditField_4.Enable = "on";
                    app.DurationEditField_4.Enable = "on";
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OptogeneticsControlLiangLabUIFigure = uifigure('Visible', 'off');
            app.OptogeneticsControlLiangLabUIFigure.Name = 'Optogenetics Control @ Liang Lab';
            app.OptogeneticsControlLiangLabUIFigure.Icon = fullfile(pathToMLAPP, 'brain_icon.jpg');
            app.ConfigMenu = uimenu(app.OptogeneticsControlLiangLabUIFigure);
            app.ConfigMenu.Text = 'Config';
            app.SaveConfigMenu = uimenu(app.ConfigMenu);
            app.SaveConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigMenuSelected, true);
            app.SaveConfigMenu.Text = 'Save Config';
            app.LoadConfigMenu = uimenu(app.ConfigMenu);
            app.LoadConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigMenuSelected, true);
            app.LoadConfigMenu.Text = 'Load Config';
            app.StartButton = uibutton(app.OptogeneticsControlLiangLabUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = fullfile(pathToMLAPP, 'mouse_icon.jpg');
            app.StartButton.Text = 'Start';
            app.StimulationPeriodPanel = uipanel(app.OptogeneticsControlLiangLabUIFigure);
            app.StimulationPeriodPanel.Title = 'Stimulation Period';
            app.CycleNumberEditFieldLabel = uilabel(app.StimulationPeriodPanel);
            app.CycleNumberEditFieldLabel.Text = 'Cycle Number';
            app.CycleNumberEditField = uieditfield(app.StimulationPeriodPanel, 'text');
            app.CycleNumberEditField.ValueChangedFcn = createCallbackFcn(app, @CycleNumberEditFieldValueChanged, true);
            app.CycleNumberEditField.Value = '20';
            app.Stage1SwitchLabel = uilabel(app.StimulationPeriodPanel);
            app.Stage1SwitchLabel.Text = 'Stage 1';
            app.Stage1Switch = uiswitch(app.StimulationPeriodPanel, 'slider');
            app.Stage1Switch.ValueChangedFcn = createCallbackFcn(app, @Stage1SwitchValueChanged, true);
            app.Stage1Switch.Value = 'On';
            app.RedDutyRatioEditFieldLabel = uilabel(app.StimulationPeriodPanel);
            app.RedDutyRatioEditFieldLabel.Text = 'Red Duty Ratio';
            app.RedDutyRatioEditField_1 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.RedDutyRatioEditField_1.ValueChangedFcn = createCallbackFcn(app, @RedDutyRatioEditField_1ValueChanged, true);
            app.RedDutyRatioEditField_1.Value = '20';
            app.RedFreqEditFieldLabel = uilabel(app.StimulationPeriodPanel);
            app.RedFreqEditFieldLabel.Text = 'Red Freq';
            app.RedFreqEditField_1 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.RedFreqEditField_1.ValueChangedFcn = createCallbackFcn(app, @RedFreqEditField_1ValueChanged, true);
            app.RedFreqEditField_1.Value = '20';
            app.BlueFreqEditFieldLabel = uilabel(app.StimulationPeriodPanel);
            app.BlueFreqEditFieldLabel.Text = 'Blue Freq';
            app.BlueFreqEditField_1 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.BlueFreqEditField_1.ValueChangedFcn = createCallbackFcn(app, @BlueFreqEditField_1ValueChanged, true);
            app.BlueFreqEditField_1.Value = '1';
            app.BlueDutyRatioEditFieldLabel = uilabel(app.StimulationPeriodPanel);
            app.BlueDutyRatioEditFieldLabel.Text = 'Blue Duty Ratio';
            app.BlueDutyRatioEditField_1 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.BlueDutyRatioEditField_1.ValueChangedFcn = createCallbackFcn(app, @BlueDutyRatioEditField_1ValueChanged, true);
            app.BlueDutyRatioEditField_1.Value = '0';
            app.RedDutyRatioEditField_2Label = uilabel(app.StimulationPeriodPanel);
            app.RedDutyRatioEditField_2Label.Text = 'Red Duty Ratio';
            app.RedDutyRatioEditField_2 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.RedDutyRatioEditField_2.ValueChangedFcn = createCallbackFcn(app, @RedDutyRatioEditField_2ValueChanged, true);
            app.RedDutyRatioEditField_2.Value = '20';
            app.RedFreqEditField_2Label = uilabel(app.StimulationPeriodPanel);
            app.RedFreqEditField_2Label.Text = 'Red Freq';
            app.RedFreqEditField_2 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.RedFreqEditField_2.ValueChangedFcn = createCallbackFcn(app, @RedFreqEditField_2ValueChanged, true);
            app.RedFreqEditField_2.Value = '20';
            app.BlueFreqEditField_2Label = uilabel(app.StimulationPeriodPanel);
            app.BlueFreqEditField_2Label.Text = 'Blue Freq';
            app.BlueFreqEditField_2 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.BlueFreqEditField_2.ValueChangedFcn = createCallbackFcn(app, @BlueFreqEditField_2ValueChanged, true);
            app.BlueFreqEditField_2.Value = '1';
            app.BlueDutyRatioEditField_2Label = uilabel(app.StimulationPeriodPanel);
            app.BlueDutyRatioEditField_2Label.Text = 'Blue Duty Ratio';
            app.BlueDutyRatioEditField_2 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.BlueDutyRatioEditField_2.ValueChangedFcn = createCallbackFcn(app, @BlueDutyRatioEditField_2ValueChanged, true);
            app.BlueDutyRatioEditField_2.Value = '100';
            app.Stage2SwitchLabel = uilabel(app.StimulationPeriodPanel);
            app.Stage2SwitchLabel.Text = 'Stage 2';
            app.Stage2Switch = uiswitch(app.StimulationPeriodPanel, 'slider');
            app.Stage2Switch.ValueChangedFcn = createCallbackFcn(app, @Stage2SwitchValueChanged, true);
            app.Stage2Switch.Value = 'On';
            app.RedDutyRatioEditField_3Label = uilabel(app.StimulationPeriodPanel);
            app.RedDutyRatioEditField_3Label.Text = 'Red Duty Ratio';
            app.RedDutyRatioEditField_3 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.RedDutyRatioEditField_3.ValueChangedFcn = createCallbackFcn(app, @RedDutyRatioEditField_3ValueChanged, true);
            app.RedDutyRatioEditField_3.Value = '0';
            app.RedFreqEditField_3Label = uilabel(app.StimulationPeriodPanel);
            app.RedFreqEditField_3Label.Text = 'Red Freq';
            app.RedFreqEditField_3 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.RedFreqEditField_3.ValueChangedFcn = createCallbackFcn(app, @RedFreqEditField_3ValueChanged, true);
            app.RedFreqEditField_3.Value = '1';
            app.BlueFreqEditField_3Label = uilabel(app.StimulationPeriodPanel);
            app.BlueFreqEditField_3Label.Text = 'Blue Freq';
            app.BlueFreqEditField_3 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.BlueFreqEditField_3.ValueChangedFcn = createCallbackFcn(app, @BlueFreqEditField_3ValueChanged, true);
            app.BlueFreqEditField_3.Value = '1';
            app.BlueDutyRatioEditField_3Label = uilabel(app.StimulationPeriodPanel);
            app.BlueDutyRatioEditField_3Label.Text = 'Blue Duty Ratio';
            app.BlueDutyRatioEditField_3 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.BlueDutyRatioEditField_3.ValueChangedFcn = createCallbackFcn(app, @BlueDutyRatioEditField_3ValueChanged, true);
            app.BlueDutyRatioEditField_3.Value = '100';
            app.Stage3SwitchLabel = uilabel(app.StimulationPeriodPanel);
            app.Stage3SwitchLabel.Text = 'Stage 3';
            app.Stage3Switch = uiswitch(app.StimulationPeriodPanel, 'slider');
            app.Stage3Switch.ValueChangedFcn = createCallbackFcn(app, @Stage3SwitchValueChanged, true);
            app.Stage3Switch.Value = 'On';
            app.RedDutyRatioEditField_4Label = uilabel(app.StimulationPeriodPanel);
            app.RedDutyRatioEditField_4Label.Text = 'Red Duty Ratio';
            app.RedDutyRatioEditField_4 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.RedDutyRatioEditField_4.ValueChangedFcn = createCallbackFcn(app, @RedDutyRatioEditField_4ValueChanged, true);
            app.RedDutyRatioEditField_4.Value = '0';
            app.RedFreqEditField_4Label = uilabel(app.StimulationPeriodPanel);
            app.RedFreqEditField_4Label.Text = 'Red Freq';
            app.RedFreqEditField_4 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.RedFreqEditField_4.ValueChangedFcn = createCallbackFcn(app, @RedFreqEditField_4ValueChanged, true);
            app.RedFreqEditField_4.Value = '1';
            app.BlueFreqEditField_4Label = uilabel(app.StimulationPeriodPanel);
            app.BlueFreqEditField_4Label.Text = 'Blue Freq';
            app.BlueFreqEditField_4 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.BlueFreqEditField_4.ValueChangedFcn = createCallbackFcn(app, @BlueFreqEditField_4ValueChanged, true);
            app.BlueFreqEditField_4.Value = '1';
            app.BlueDutyRatioEditField_4Label = uilabel(app.StimulationPeriodPanel);
            app.BlueDutyRatioEditField_4Label.Text = 'Blue Duty Ratio';
            app.BlueDutyRatioEditField_4 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.BlueDutyRatioEditField_4.ValueChangedFcn = createCallbackFcn(app, @BlueDutyRatioEditField_4ValueChanged, true);
            app.BlueDutyRatioEditField_4.Value = '0';
            app.Stage4SwitchLabel = uilabel(app.StimulationPeriodPanel);
            app.Stage4SwitchLabel.Text = 'Stage 4';
            app.Stage4Switch = uiswitch(app.StimulationPeriodPanel, 'slider');
            app.Stage4Switch.ValueChangedFcn = createCallbackFcn(app, @Stage4SwitchValueChanged, true);
            app.Stage4Switch.Value = 'On';
            app.FrequnitHzLabel = uilabel(app.StimulationPeriodPanel);
            app.FrequnitHzLabel.Text = 'Freq unit: Hz';
            app.DutyRatiounitLabel = uilabel(app.StimulationPeriodPanel);
            app.DutyRatiounitLabel.Text = 'Duty Ratio unit: %';
            app.DurationEditFieldLabel = uilabel(app.StimulationPeriodPanel);
            app.DurationEditFieldLabel.Text = 'Duration';
            app.DurationEditField_1 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.DurationEditField_1.ValueChangedFcn = createCallbackFcn(app, @DurationEditField_1ValueChanged, true);
            app.DurationEditField_1.Value = '2';
            app.DurationEditField_2Label = uilabel(app.StimulationPeriodPanel);
            app.DurationEditField_2Label.Text = 'Duration';
            app.DurationEditField_2 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.DurationEditField_2.ValueChangedFcn = createCallbackFcn(app, @DurationEditField_2ValueChanged, true);
            app.DurationEditField_2.Value = '4';
            app.DurationEditField_3Label = uilabel(app.StimulationPeriodPanel);
            app.DurationEditField_3Label.Text = 'Duration';
            app.DurationEditField_3 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.DurationEditField_3.ValueChangedFcn = createCallbackFcn(app, @DurationEditField_3ValueChanged, true);
            app.DurationEditField_3.Value = '1';
            app.DurationEditField_4Label = uilabel(app.StimulationPeriodPanel);
            app.DurationEditField_4Label.Text = 'Duration';
            app.DurationEditField_4 = uieditfield(app.StimulationPeriodPanel, 'text');
            app.DurationEditField_4.ValueChangedFcn = createCallbackFcn(app, @DurationEditField_4ValueChanged, true);
            app.DurationEditField_4.Value = '23';
            app.DurationunitsLabel = uilabel(app.StimulationPeriodPanel);
            app.DurationunitsLabel.Text = 'Duration unit: s';
            app.unitsLabel_2 = uilabel(app.OptogeneticsControlLiangLabUIFigure);
            app.unitsLabel_2.Text = 'unit: s';
            app.PostPeriodEditField_2Label = uilabel(app.OptogeneticsControlLiangLabUIFigure);
            app.PostPeriodEditField_2Label.Text = 'Post-Period';
            app.PostPeriodEditField = uieditfield(app.OptogeneticsControlLiangLabUIFigure, 'text');
            app.PostPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @PostPeriodEditFieldValueChanged, true);
            app.PostPeriodEditField.Value = '120';
            app.unitsLabel = uilabel(app.OptogeneticsControlLiangLabUIFigure);
            app.unitsLabel.Text = 'unit: s';
            app.PrePeriodEditFieldLabel = uilabel(app.OptogeneticsControlLiangLabUIFigure);
            app.PrePeriodEditFieldLabel.Text = 'Pre-Period';
            app.PrePeriodEditField = uieditfield(app.OptogeneticsControlLiangLabUIFigure, 'text');
            app.PrePeriodEditField.ValueChangedFcn = createCallbackFcn(app, @PrePeriodEditFieldValueChanged, true);
            app.PrePeriodEditField.Value = '120';
            app.OutputTextAreaLabel = uilabel(app.OptogeneticsControlLiangLabUIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.OutputTextArea = uitextarea(app.OptogeneticsControlLiangLabUIFigure);
            app.OutputTextArea.Editable = 'off';
            app.HelpButton = uibutton(app.OptogeneticsControlLiangLabUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.OptogeneticsControlLiangLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optocontrol_v4point1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptogeneticsControlLiangLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptogeneticsControlLiangLabUIFigure)
        end
    end
end