classdef OptoPhotometry_control_v6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OptoPhotometryControlLiangLabUIFigure  matlab.ui.Figure
        ConfigMenu                    matlab.ui.container.Menu
        LoadConfigMenu                matlab.ui.container.Menu
        SaveConfigMenu                matlab.ui.container.Menu
        VisualStimuliSwitchLabel      matlab.ui.control.Label
        HelpButton                    matlab.ui.control.Button
        RotaryEncoderSwitchLabel      matlab.ui.control.Label
        CameraSwitchLabel             matlab.ui.control.Label
        PhotometrySwitchLabel         matlab.ui.control.Label
        StimulationPeriodEditFieldLabel  matlab.ui.control.Label
        OutputTextAreaLabel           matlab.ui.control.Label
        PrePeriodEditFieldLabel       matlab.ui.control.Label
        PrePeriodEditField            matlab.ui.control.EditField
        unitsLabel_1                  matlab.ui.control.Label
        StimulationPeriodEditField    matlab.ui.control.EditField
        unitsLabel_2                  matlab.ui.control.Label
        VisualStimuliSwitch           matlab.ui.control.Switch
        PhotometrySwitch              matlab.ui.control.Switch
        CameraSwitch                  matlab.ui.control.Switch
        RotaryEncoderSwitch           matlab.ui.control.Switch
        PostPeriodEditField_2Label    matlab.ui.control.Label
        StimulationPeriodPanel        matlab.ui.container.Panel
        StartTimeDurationunitsLabel   matlab.ui.control.Label
        FrequnitHzLabel               matlab.ui.control.Label
        DutyRatiounitLabel            matlab.ui.control.Label
        RedPanel                      matlab.ui.container.Panel
        DurationEditField_24Label     matlab.ui.control.Label
        DurationEditField_23Label     matlab.ui.control.Label
        DurationEditField_22Label     matlab.ui.control.Label
        DurationEditField_21Label     matlab.ui.control.Label
        RedStartTimeEditField         matlab.ui.control.EditField
        Stage4Switch_6Label           matlab.ui.control.Label
        FreqEditField_22Label         matlab.ui.control.Label
        DutyRatioEditField_24Label    matlab.ui.control.Label
        Stage3Switch_6Label           matlab.ui.control.Label
        FreqEditField_21Label         matlab.ui.control.Label
        RedLeftTimeEditField          matlab.ui.control.EditField
        LeftTimeLabel                 matlab.ui.control.Label
        RedStage1Switch               matlab.ui.control.Switch
        RedStage1DurationEditField    matlab.ui.control.EditField
        DutyRatioEditField_23Label    matlab.ui.control.Label
        RedStage1FreqEditField        matlab.ui.control.EditField
        RedStage1DutyRatioEditField   matlab.ui.control.EditField
        RedStage2Switch               matlab.ui.control.Switch
        RedStage2DurationEditField    matlab.ui.control.EditField
        RedStage2FreqEditField        matlab.ui.control.EditField
        Stage2Switch_6Label           matlab.ui.control.Label
        RedStage2DutyRatioEditField   matlab.ui.control.EditField
        RedStage3Switch               matlab.ui.control.Switch
        RedStage3DurationEditField    matlab.ui.control.EditField
        RedStage3FreqEditField        matlab.ui.control.EditField
        RedStage3DutyRatioEditField   matlab.ui.control.EditField
        RedStage4Switch               matlab.ui.control.Switch
        RedStage4DurationEditField    matlab.ui.control.EditField
        RedStage4FreqEditField        matlab.ui.control.EditField
        RedStage4DutyRatioEditField   matlab.ui.control.EditField
        FreqEditField_20Label         matlab.ui.control.Label
        DutyRatioEditField_22Label    matlab.ui.control.Label
        Stage1Switch_6Label           matlab.ui.control.Label
        FreqEditFieldLabel            matlab.ui.control.Label
        DutyRatioEditField_21Label    matlab.ui.control.Label
        StartTimeEditFieldLabel       matlab.ui.control.Label
        BluePanel                     matlab.ui.container.Panel
        DurationEditField_32Label     matlab.ui.control.Label
        DurationEditField_31Label     matlab.ui.control.Label
        DurationEditField_30Label     matlab.ui.control.Label
        DurationEditField_29Label     matlab.ui.control.Label
        Stage4Switch_8Label           matlab.ui.control.Label
        BlueStartTimeEditField        matlab.ui.control.EditField
        BlueLeftTimeEditField         matlab.ui.control.EditField
        StartTimeEditField_4Label_2   matlab.ui.control.Label
        BlueStage1Switch              matlab.ui.control.Switch
        Stage3Switch_8Label           matlab.ui.control.Label
        BlueStage1DurationEditField   matlab.ui.control.EditField
        BlueStage1FreqEditField       matlab.ui.control.EditField
        BlueStage1DutyRatioEditField  matlab.ui.control.EditField
        BlueStage2Switch              matlab.ui.control.Switch
        Stage2Switch_8Label           matlab.ui.control.Label
        Stage1Switch_8Label           matlab.ui.control.Label
        BlueStage2DurationEditField   matlab.ui.control.EditField
        BlueStage2FreqEditField       matlab.ui.control.EditField
        BlueStage2DutyRatioEditField  matlab.ui.control.EditField
        BlueStage3Switch              matlab.ui.control.Switch
        BlueStage3DurationEditField   matlab.ui.control.EditField
        BlueStage3FreqEditField       matlab.ui.control.EditField
        BlueStage3DutyRatioEditField  matlab.ui.control.EditField
        BlueStage4Switch              matlab.ui.control.Switch
        DutyRatioEditField_32Label    matlab.ui.control.Label
        BlueStage4DurationEditField   matlab.ui.control.EditField
        BlueStage4FreqEditField       matlab.ui.control.EditField
        FreqEditField_30Label         matlab.ui.control.Label
        BlueStage4DutyRatioEditField  matlab.ui.control.EditField
        DutyRatioEditField_31Label    matlab.ui.control.Label
        FreqEditField_29Label         matlab.ui.control.Label
        DutyRatioEditField_30Label    matlab.ui.control.Label
        FreqEditField_28Label         matlab.ui.control.Label
        DutyRatioEditField_29Label    matlab.ui.control.Label
        FreqEditField_27Label         matlab.ui.control.Label
        StartTimeEditField_4Label     matlab.ui.control.Label
        UVPanel                       matlab.ui.container.Panel
        StartTimeEditField_3Label_2   matlab.ui.control.Label
        DurationEditField_28Label     matlab.ui.control.Label
        DurationEditField_27Label     matlab.ui.control.Label
        DurationEditField_26Label     matlab.ui.control.Label
        DurationEditField_25Label     matlab.ui.control.Label
        UVStartTimeEditField          matlab.ui.control.EditField
        UVLeftTimeEditField           matlab.ui.control.EditField
        UVStage1Switch                matlab.ui.control.Switch
        Stage4Switch_7Label           matlab.ui.control.Label
        Stage3Switch_7Label           matlab.ui.control.Label
        UVStage1DurationEditField     matlab.ui.control.EditField
        UVStage1FreqEditField         matlab.ui.control.EditField
        UVStage1DutyRatioEditField    matlab.ui.control.EditField
        UVStage2Switch                matlab.ui.control.Switch
        Stage2Switch_7Label           matlab.ui.control.Label
        Stage1Switch_7Label           matlab.ui.control.Label
        DutyRatioEditField_28Label    matlab.ui.control.Label
        FreqEditField_26Label         matlab.ui.control.Label
        UVStage2DurationEditField     matlab.ui.control.EditField
        UVStage2FreqEditField         matlab.ui.control.EditField
        UVStage2DutyRatioEditField    matlab.ui.control.EditField
        UVStage3Switch                matlab.ui.control.Switch
        DutyRatioEditField_27Label    matlab.ui.control.Label
        UVStage3DurationEditField     matlab.ui.control.EditField
        UVStage3FreqEditField         matlab.ui.control.EditField
        FreqEditField_25Label         matlab.ui.control.Label
        DutyRatioEditField_26Label    matlab.ui.control.Label
        FreqEditField_24Label         matlab.ui.control.Label
        UVStage3DutyRatioEditField    matlab.ui.control.EditField
        UVStage4Switch                matlab.ui.control.Switch
        UVStage4DurationEditField     matlab.ui.control.EditField
        UVStage4FreqEditField         matlab.ui.control.EditField
        UVStage4DutyRatioEditField    matlab.ui.control.EditField
        DutyRatioEditField_25Label    matlab.ui.control.Label
        FreqEditField_23Label         matlab.ui.control.Label
        StartTimeEditField_3Label     matlab.ui.control.Label
        PostPeriodEditField           matlab.ui.control.EditField
        unitsLabel_3                  matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
        OutputTextArea                matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        pre_period;

        stimulation_period;
        visual_stimuli_switch;
        photometry_switch;
        camera_switch;
        rotary_encoder_switch;
        
        red_start_time;
        red_left_time;
        red_stage1_switch;
        red_stage1_duration;
        red_stage1_freq;
        red_stage1_duty_ratio;
        red_stage2_switch;
        red_stage2_duration;
        red_stage2_freq;
        red_stage2_duty_ratio;
        red_stage3_switch;
        red_stage3_duration;
        red_stage3_freq;
        red_stage3_duty_ratio;
        red_stage4_switch;
        red_stage4_duration;
        red_stage4_freq;
        red_stage4_duty_ratio;
        
        blue_start_time;
        blue_left_time;
        blue_stage1_switch;
        blue_stage1_duration;
        blue_stage1_freq;
        blue_stage1_duty_ratio;
        blue_stage2_switch;
        blue_stage2_duration;
        blue_stage2_freq;
        blue_stage2_duty_ratio;
        blue_stage3_switch;
        blue_stage3_duration;
        blue_stage3_freq;
        blue_stage3_duty_ratio;
        blue_stage4_switch;
        blue_stage4_duration;
        blue_stage4_freq;
        blue_stage4_duty_ratio;
        
        uv_start_time;
        uv_left_time;
        uv_stage1_switch;
        uv_stage1_duration;
        uv_stage1_freq;
        uv_stage1_duty_ratio;
        uv_stage2_switch;
        uv_stage2_duration;
        uv_stage2_freq;
        uv_stage2_duty_ratio;
        uv_stage3_switch;
        uv_stage3_duration;
        uv_stage3_freq;
        uv_stage3_duty_ratio;
        uv_stage4_switch;
        uv_stage4_duration;
        uv_stage4_freq;
        uv_stage4_duty_ratio;
        
        post_period;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pre_period = str2double(app.PrePeriodEditField.Value);

            app.stimulation_period = str2double(app.StimulationPeriodEditField.Value);
            app.visual_stimuli_switch = string(app.VisualStimuliSwitch.Value);
            app.photometry_switch = string(app.PhotometrySwitch.Value);
            app.camera_switch = string(app.CameraSwitch.Value);
            app.rotary_encoder_switch = string(app.RotaryEncoderSwitch.Value);
            
            app.red_start_time = str2double(app.RedStartTimeEditField.Value);
            app.red_left_time = str2double(app.RedLeftTimeEditField.Value);
            app.red_stage1_switch = string(app.RedStage1Switch.Value);
            app.red_stage1_duration = str2double(app.RedStage1DurationEditField.Value);
            app.red_stage1_freq = str2double(app.RedStage1FreqEditField.Value);
            app.red_stage1_duty_ratio = str2double(app.RedStage1DutyRatioEditField.Value);
            app.red_stage2_switch = string(app.RedStage2Switch.Value);
            app.red_stage2_duration = str2double(app.RedStage2DurationEditField.Value);
            app.red_stage2_freq = str2double(app.RedStage2FreqEditField.Value);
            app.red_stage2_duty_ratio = str2double(app.RedStage2DutyRatioEditField.Value);
            app.red_stage3_switch = string(app.RedStage3Switch.Value);
            app.red_stage3_duration = str2double(app.RedStage3DurationEditField.Value);
            app.red_stage3_freq = str2double(app.RedStage3FreqEditField.Value);
            app.red_stage3_duty_ratio = str2double(app.RedStage3DutyRatioEditField.Value);
            app.red_stage4_switch = string(app.RedStage4Switch.Value);
            app.red_stage4_duration = str2double(app.RedStage4DurationEditField.Value);
            app.red_stage4_freq = str2double(app.RedStage4FreqEditField.Value);
            app.red_stage4_duty_ratio = str2double(app.RedStage4DutyRatioEditField.Value);
            
            app.blue_start_time = str2double(app.BlueStartTimeEditField.Value);
            app.blue_left_time = str2double(app.BlueLeftTimeEditField.Value);
            app.blue_stage1_switch = string(app.BlueStage1Switch.Value);
            app.blue_stage1_duration = str2double(app.BlueStage1DurationEditField.Value);
            app.blue_stage1_freq = str2double(app.BlueStage1FreqEditField.Value);
            app.blue_stage1_duty_ratio = str2double(app.BlueStage1DutyRatioEditField.Value);
            app.blue_stage2_switch = string(app.BlueStage2Switch.Value);
            app.blue_stage2_duration = str2double(app.BlueStage2DurationEditField.Value);
            app.blue_stage2_freq = str2double(app.BlueStage2FreqEditField.Value);
            app.blue_stage2_duty_ratio = str2double(app.BlueStage2DutyRatioEditField.Value);
            app.blue_stage3_switch = string(app.BlueStage3Switch.Value);
            app.blue_stage3_duration = str2double(app.BlueStage3DurationEditField.Value);
            app.blue_stage3_freq = str2double(app.BlueStage3FreqEditField.Value);
            app.blue_stage3_duty_ratio = str2double(app.BlueStage3DutyRatioEditField.Value);
            app.blue_stage4_switch = string(app.BlueStage4Switch.Value);
            app.blue_stage4_duration = str2double(app.BlueStage4DurationEditField.Value);
            app.blue_stage4_freq = str2double(app.BlueStage4FreqEditField.Value);
            app.blue_stage4_duty_ratio = str2double(app.BlueStage4DutyRatioEditField.Value);
            
            app.uv_start_time = str2double(app.UVStartTimeEditField.Value);
            app.uv_left_time = str2double(app.UVLeftTimeEditField.Value);
            app.uv_stage1_switch = string(app.UVStage1Switch.Value);
            app.uv_stage1_duration = str2double(app.UVStage1DurationEditField.Value);
            app.uv_stage1_freq = str2double(app.UVStage1FreqEditField.Value);
            app.uv_stage1_duty_ratio = str2double(app.UVStage1DutyRatioEditField.Value);
            app.uv_stage2_switch = string(app.UVStage2Switch.Value);
            app.uv_stage2_duration = str2double(app.UVStage2DurationEditField.Value);
            app.uv_stage2_freq = str2double(app.UVStage2FreqEditField.Value);
            app.uv_stage2_duty_ratio = str2double(app.UVStage2DutyRatioEditField.Value);
            app.uv_stage3_switch = string(app.UVStage3Switch.Value);
            app.uv_stage3_duration = str2double(app.UVStage3DurationEditField.Value);
            app.uv_stage3_freq = str2double(app.UVStage3FreqEditField.Value);
            app.uv_stage3_duty_ratio = str2double(app.UVStage3DutyRatioEditField.Value);
            app.uv_stage4_switch = string(app.UVStage4Switch.Value);
            app.uv_stage4_duration = str2double(app.UVStage4DurationEditField.Value);
            app.uv_stage4_freq = str2double(app.UVStage4FreqEditField.Value);
            app.uv_stage4_duty_ratio = str2double(app.UVStage4DutyRatioEditField.Value);
            
            app.post_period = str2double(app.PostPeriodEditField.Value);
            
            app.OutputTextArea.Value = "OptoPhotometry Control Output Window";


            if app.red_stage1_switch == "Off"
                app.RedStage1DurationEditField.Enable = "off";
                app.RedStage1FreqEditField.Enable = "off";
                app.RedStage1DutyRatioEditField.Enable = "off";
            elseif app.red_stage1_switch == "On"
                app.RedStage1DurationEditField.Enable = "on";
                app.RedStage1FreqEditField.Enable = "on";
                app.RedStage1DutyRatioEditField.Enable = "on";
            end
            
            if app.red_stage2_switch == "Off"
                app.RedStage2DurationEditField.Enable = "off";
                app.RedStage2FreqEditField.Enable = "off";
                app.RedStage2DutyRatioEditField.Enable = "off";
            elseif app.red_stage2_switch == "On"
                app.RedStage2DurationEditField.Enable = "on";
                app.RedStage2FreqEditField.Enable = "on";
                app.RedStage2DutyRatioEditField.Enable = "on";
            end
            
            if app.red_stage3_switch == "Off"
                app.RedStage3DurationEditField.Enable = "off";
                app.RedStage3FreqEditField.Enable = "off";
                app.RedStage3DutyRatioEditField.Enable = "off";
            elseif app.red_stage3_switch == "On"
                app.RedStage3DurationEditField.Enable = "on";
                app.RedStage3FreqEditField.Enable = "on";
                app.RedStage3DutyRatioEditField.Enable = "on";
            end
            
            if app.red_stage4_switch == "Off"
                app.RedStage4DurationEditField.Enable = "off";
                app.RedStage4FreqEditField.Enable = "off";
                app.RedStage4DutyRatioEditField.Enable = "off";
            elseif app.red_stage4_switch == "On"
                app.RedStage4DurationEditField.Enable = "on";
                app.RedStage4FreqEditField.Enable = "on";
                app.RedStage4DutyRatioEditField.Enable = "on";
            end
            
            if app.blue_stage1_switch == "Off"
                app.BlueStage1DurationEditField.Enable = "off";
                app.BlueStage1FreqEditField.Enable = "off";
                app.BlueStage1DutyRatioEditField.Enable = "off";
            elseif app.blue_stage1_switch == "On"
                app.BlueStage1DurationEditField.Enable = "on";
                app.BlueStage1FreqEditField.Enable = "on";
                app.BlueStage1DutyRatioEditField.Enable = "on";
            end
            
            if app.blue_stage2_switch == "Off"
                app.BlueStage2DurationEditField.Enable = "off";
                app.BlueStage2FreqEditField.Enable = "off";
                app.BlueStage2DutyRatioEditField.Enable = "off";
            elseif app.blue_stage2_switch == "On"
                app.BlueStage2DurationEditField.Enable = "on";
                app.BlueStage2FreqEditField.Enable = "on";
                app.BlueStage2DutyRatioEditField.Enable = "on";
            end
            
            if app.blue_stage3_switch == "Off"
                app.BlueStage3DurationEditField.Enable = "off";
                app.BlueStage3FreqEditField.Enable = "off";
                app.BlueStage3DutyRatioEditField.Enable = "off";
            elseif app.blue_stage3_switch == "On"
                app.BlueStage3DurationEditField.Enable = "on";
                app.BlueStage3FreqEditField.Enable = "on";
                app.BlueStage3DutyRatioEditField.Enable = "on";
            end
            
            if app.blue_stage4_switch == "Off"
                app.BlueStage4DurationEditField.Enable = "off";
                app.BlueStage4FreqEditField.Enable = "off";
                app.BlueStage4DutyRatioEditField.Enable = "off";
            elseif app.blue_stage4_switch == "On"
                app.BlueStage4DurationEditField.Enable = "on";
                app.BlueStage4FreqEditField.Enable = "on";
                app.BlueStage4DutyRatioEditField.Enable = "on";
            end
            
            if app.uv_stage1_switch == "Off"
                app.UVStage1DurationEditField.Enable = "off";
                app.UVStage1FreqEditField.Enable = "off";
                app.UVStage1DutyRatioEditField.Enable = "off";
            elseif app.uv_stage1_switch == "On"
                app.UVStage1DurationEditField.Enable = "on";
                app.UVStage1FreqEditField.Enable = "on";
                app.UVStage1DutyRatioEditField.Enable = "on";
            end
            
            if app.uv_stage2_switch == "Off"
                app.UVStage2DurationEditField.Enable = "off";
                app.UVStage2FreqEditField.Enable = "off";
                app.UVStage2DutyRatioEditField.Enable = "off";
            elseif app.uv_stage2_switch == "On"
                app.UVStage2DurationEditField.Enable = "on";
                app.UVStage2FreqEditField.Enable = "on";
                app.UVStage2DutyRatioEditField.Enable = "on";
            end
            
            if app.uv_stage3_switch == "Off"
                app.UVStage3DurationEditField.Enable = "off";
                app.UVStage3FreqEditField.Enable = "off";
                app.UVStage3DutyRatioEditField.Enable = "off";
            elseif app.uv_stage3_switch == "On"
                app.UVStage3DurationEditField.Enable = "on";
                app.UVStage3FreqEditField.Enable = "on";
                app.UVStage3DutyRatioEditField.Enable = "on";
            end
            
            if app.uv_stage4_switch == "Off"
                app.UVStage4DurationEditField.Enable = "off";
                app.UVStage4FreqEditField.Enable = "off";
                app.UVStage4DutyRatioEditField.Enable = "off";
            elseif app.uv_stage4_switch == "On"
                app.UVStage4DurationEditField.Enable = "on";
                app.UVStage4FreqEditField.Enable = "on";
                app.UVStage4DutyRatioEditField.Enable = "on";
            end
        end

        % Menu selected function: LoadConfigMenu
        function LoadConfigMenuSelected(app, event)
            % Open the file selection dialog
            [fileName, filePath] = uigetfile('*.json', 'Select JSON File');
        
            % Check if the user selected a file
            if fileName
                % Create the full file path
                fullFilePath = fullfile(filePath, fileName);
        
                % Read data from the selected file
                fid = fopen(fullFilePath, 'r');
                jsonStr = fread(fid, '*char')';
                fclose(fid);
        
                % Parse JSON data into a struct
                config = jsondecode(jsonStr);
                
                app.PrePeriodEditField.Value = num2str(config.pre_period);
                
                app.StimulationPeriodEditField.Value = num2str(config.stimulation_period);
                app.VisualStimuliSwitch.Value = config.visualstimuli_switch;
                app.PhotometrySwitch.Value = config.photometry_switch;
                app.CameraSwitch.Value = config.camera_switch;
                app.RotaryEncoderSwitch.Value = config.rotary_encoder_switch;
                
                app.RedStartTimeEditField.Value = num2str(config.red_start_time);
                app.RedLeftTimeEditField.Value = num2str(config.red_left_time);
                app.RedStage1Switch.Value = config.red_stage1_switch;
                app.RedStage1DurationEditField.Value = num2str(config.red_stage1_duration);
                app.RedStage1FreqEditField.Value = num2str(config.red_stage1_freq);
                app.RedStage1DutyRatioEditField.Value = num2str(config.red_stage1_duty_ratio);
                app.RedStage2Switch.Value = config.red_stage2_switch;
                app.RedStage2DurationEditField.Value = num2str(config.red_stage2_duration);
                app.RedStage2FreqEditField.Value = num2str(config.red_stage2_freq);
                app.RedStage2DutyRatioEditField.Value = num2str(config.red_stage2_duty_ratio);
                app.RedStage3Switch.Value = config.red_stage3_switch;
                app.RedStage3DurationEditField.Value = num2str(config.red_stage3_duration);
                app.RedStage3FreqEditField.Value = num2str(config.red_stage3_freq);
                app.RedStage3DutyRatioEditField.Value = num2str(config.red_stage3_duty_ratio);
                app.RedStage4Switch.Value = config.red_stage4_switch;
                app.RedStage4DurationEditField.Value = num2str(config.red_stage4_duration);
                app.RedStage4FreqEditField.Value = num2str(config.red_stage4_freq);
                app.RedStage4DutyRatioEditField.Value = num2str(config.red_stage4_duty_ratio);
                
                app.BlueStartTimeEditField.Value = num2str(config.blue_start_time);
                app.BlueLeftTimeEditField.Value = num2str(config.blue_left_time);
                app.BlueStage1Switch.Value = config.blue_stage1_switch;
                app.BlueStage1DurationEditField.Value = num2str(config.blue_stage1_duration);
                app.BlueStage1FreqEditField.Value = num2str(config.blue_stage1_freq);
                app.BlueStage1DutyRatioEditField.Value = num2str(config.blue_stage1_duty_ratio);
                app.BlueStage2Switch.Value = config.blue_stage2_switch;
                app.BlueStage2DurationEditField.Value = num2str(config.blue_stage2_duration);
                app.BlueStage2FreqEditField.Value = num2str(config.blue_stage2_freq);
                app.BlueStage2DutyRatioEditField.Value = num2str(config.blue_stage2_duty_ratio);
                app.BlueStage3Switch.Value = config.blue_stage3_switch;
                app.BlueStage3DurationEditField.Value = num2str(config.blue_stage3_duration);
                app.BlueStage3FreqEditField.Value = num2str(config.blue_stage3_freq);
                app.BlueStage3DutyRatioEditField.Value = num2str(config.blue_stage3_duty_ratio);
                app.BlueStage4Switch.Value = config.blue_stage4_switch;
                app.BlueStage4DurationEditField.Value = num2str(config.blue_stage4_duration);
                app.BlueStage4FreqEditField.Value = num2str(config.blue_stage4_freq);
                app.BlueStage4DutyRatioEditField.Value = num2str(config.blue_stage4_duty_ratio);
                
                app.UVStartTimeEditField.Value = num2str(config.uv_start_time);
                app.UVLeftTimeEditField.Value = num2str(config.uv_left_time);
                app.UVStage1Switch.Value = config.uv_stage1_switch;
                app.UVStage1DurationEditField.Value = num2str(config.uv_stage1_duration);
                app.UVStage1FreqEditField.Value = num2str(config.uv_stage1_freq);
                app.UVStage1DutyRatioEditField.Value = num2str(config.uv_stage1_duty_ratio);
                app.UVStage2Switch.Value = config.uv_stage2_switch;
                app.UVStage2DurationEditField.Value = num2str(config.uv_stage2_duration);
                app.UVStage2FreqEditField.Value = num2str(config.uv_stage2_freq);
                app.UVStage2DutyRatioEditField.Value = num2str(config.uv_stage2_duty_ratio);
                app.UVStage3Switch.Value = config.uv_stage3_switch;
                app.UVStage3DurationEditField.Value = num2str(config.uv_stage3_duration);
                app.UVStage3FreqEditField.Value = num2str(config.uv_stage3_freq);
                app.UVStage3DutyRatioEditField.Value = num2str(config.uv_stage3_duty_ratio);
                app.UVStage4Switch.Value = config.uv_stage4_switch;
                app.UVStage4DurationEditField.Value = num2str(config.uv_stage4_duration);
                app.UVStage4FreqEditField.Value = num2str(config.uv_stage4_freq);
                app.UVStage4DutyRatioEditField.Value = num2str(config.uv_stage4_duty_ratio);
                
                app.PostPeriodEditField.Value = num2str(config.post_period);


                app.pre_period = str2double(app.PrePeriodEditField.Value);
                
                app.stimulation_period = str2double(app.StimulationPeriodEditField.Value);
                app.visual_stimuli_switch = string(app.VisualStimuliSwitch.Value);
                app.photometry_switch = string(app.PhotometrySwitch.Value);
                app.camera_switch = string(app.CameraSwitch.Value);
                app.rotary_encoder_switch = string(app.RotaryEncoderSwitch.Value);
                
                app.red_start_time = str2double(app.RedStartTimeEditField.Value);
                app.red_left_time = str2double(app.RedLeftTimeEditField.Value);
                app.red_stage1_switch = string(app.RedStage1Switch.Value);
                app.red_stage1_duration = str2double(app.RedStage1DurationEditField.Value);
                app.red_stage1_freq = str2double(app.RedStage1FreqEditField.Value);
                app.red_stage1_duty_ratio = str2double(app.RedStage1DutyRatioEditField.Value);
                app.red_stage2_switch = string(app.RedStage2Switch.Value);
                app.red_stage2_duration = str2double(app.RedStage2DurationEditField.Value);
                app.red_stage2_freq = str2double(app.RedStage2FreqEditField.Value);
                app.red_stage2_duty_ratio = str2double(app.RedStage2DutyRatioEditField.Value);
                app.red_stage3_switch = string(app.RedStage3Switch.Value);
                app.red_stage3_duration = str2double(app.RedStage3DurationEditField.Value);
                app.red_stage3_freq = str2double(app.RedStage3FreqEditField.Value);
                app.red_stage3_duty_ratio = str2double(app.RedStage3DutyRatioEditField.Value);
                app.red_stage4_switch = string(app.RedStage4Switch.Value);
                app.red_stage4_duration = str2double(app.RedStage4DurationEditField.Value);
                app.red_stage4_freq = str2double(app.RedStage4FreqEditField.Value);
                app.red_stage4_duty_ratio = str2double(app.RedStage4DutyRatioEditField.Value);
                
                app.blue_start_time = str2double(app.BlueStartTimeEditField.Value);
                app.blue_left_time = str2double(app.BlueLeftTimeEditField.Value);
                app.blue_stage1_switch = string(app.BlueStage1Switch.Value);
                app.blue_stage1_duration = str2double(app.BlueStage1DurationEditField.Value);
                app.blue_stage1_freq = str2double(app.BlueStage1FreqEditField.Value);
                app.blue_stage1_duty_ratio = str2double(app.BlueStage1DutyRatioEditField.Value);
                app.blue_stage2_switch = string(app.BlueStage2Switch.Value);
                app.blue_stage2_duration = str2double(app.BlueStage2DurationEditField.Value);
                app.blue_stage2_freq = str2double(app.BlueStage2FreqEditField.Value);
                app.blue_stage2_duty_ratio = str2double(app.BlueStage2DutyRatioEditField.Value);
                app.blue_stage3_switch = string(app.BlueStage3Switch.Value);
                app.blue_stage3_duration = str2double(app.BlueStage3DurationEditField.Value);
                app.blue_stage3_freq = str2double(app.BlueStage3FreqEditField.Value);
                app.blue_stage3_duty_ratio = str2double(app.BlueStage3DutyRatioEditField.Value);
                app.blue_stage4_switch = string(app.BlueStage4Switch.Value);
                app.blue_stage4_duration = str2double(app.BlueStage4DurationEditField.Value);
                app.blue_stage4_freq = str2double(app.BlueStage4FreqEditField.Value);
                app.blue_stage4_duty_ratio = str2double(app.BlueStage4DutyRatioEditField.Value);
                
                app.uv_start_time = str2double(app.UVStartTimeEditField.Value);
                app.uv_left_time = str2double(app.UVLeftTimeEditField.Value);
                app.uv_stage1_switch = string(app.UVStage1Switch.Value);
                app.uv_stage1_duration = str2double(app.UVStage1DurationEditField.Value);
                app.uv_stage1_freq = str2double(app.UVStage1FreqEditField.Value);
                app.uv_stage1_duty_ratio = str2double(app.UVStage1DutyRatioEditField.Value);
                app.uv_stage2_switch = string(app.UVStage2Switch.Value);
                app.uv_stage2_duration = str2double(app.UVStage2DurationEditField.Value);
                app.uv_stage2_freq = str2double(app.UVStage2FreqEditField.Value);
                app.uv_stage2_duty_ratio = str2double(app.UVStage2DutyRatioEditField.Value);
                app.uv_stage3_switch = string(app.UVStage3Switch.Value);
                app.uv_stage3_duration = str2double(app.UVStage3DurationEditField.Value);
                app.uv_stage3_freq = str2double(app.UVStage3FreqEditField.Value);
                app.uv_stage3_duty_ratio = str2double(app.UVStage3DutyRatioEditField.Value);
                app.uv_stage4_switch = string(app.UVStage4Switch.Value);
                app.uv_stage4_duration = str2double(app.UVStage4DurationEditField.Value);
                app.uv_stage4_freq = str2double(app.UVStage4FreqEditField.Value);
                app.uv_stage4_duty_ratio = str2double(app.UVStage4DutyRatioEditField.Value);
                
                app.post_period = str2double(app.PostPeriodEditField.Value);


                if app.red_stage1_switch == "Off"
                    app.RedStage1DurationEditField.Enable = "off";
                    app.RedStage1FreqEditField.Enable = "off";
                    app.RedStage1DutyRatioEditField.Enable = "off";
                elseif app.red_stage1_switch == "On"
                    app.RedStage1DurationEditField.Enable = "on";
                    app.RedStage1FreqEditField.Enable = "on";
                    app.RedStage1DutyRatioEditField.Enable = "on";
                end
                
                if app.red_stage2_switch == "Off"
                    app.RedStage2DurationEditField.Enable = "off";
                    app.RedStage2FreqEditField.Enable = "off";
                    app.RedStage2DutyRatioEditField.Enable = "off";
                elseif app.red_stage2_switch == "On"
                    app.RedStage2DurationEditField.Enable = "on";
                    app.RedStage2FreqEditField.Enable = "on";
                    app.RedStage2DutyRatioEditField.Enable = "on";
                end
                
                if app.red_stage3_switch == "Off"
                    app.RedStage3DurationEditField.Enable = "off";
                    app.RedStage3FreqEditField.Enable = "off";
                    app.RedStage3DutyRatioEditField.Enable = "off";
                elseif app.red_stage3_switch == "On"
                    app.RedStage3DurationEditField.Enable = "on";
                    app.RedStage3FreqEditField.Enable = "on";
                    app.RedStage3DutyRatioEditField.Enable = "on";
                end
                
                if app.red_stage4_switch == "Off"
                    app.RedStage4DurationEditField.Enable = "off";
                    app.RedStage4FreqEditField.Enable = "off";
                    app.RedStage4DutyRatioEditField.Enable = "off";
                elseif app.red_stage4_switch == "On"
                    app.RedStage4DurationEditField.Enable = "on";
                    app.RedStage4FreqEditField.Enable = "on";
                    app.RedStage4DutyRatioEditField.Enable = "on";
                end
                
                if app.blue_stage1_switch == "Off"
                    app.BlueStage1DurationEditField.Enable = "off";
                    app.BlueStage1FreqEditField.Enable = "off";
                    app.BlueStage1DutyRatioEditField.Enable = "off";
                elseif app.blue_stage1_switch == "On"
                    app.BlueStage1DurationEditField.Enable = "on";
                    app.BlueStage1FreqEditField.Enable = "on";
                    app.BlueStage1DutyRatioEditField.Enable = "on";
                end
                
                if app.blue_stage2_switch == "Off"
                    app.BlueStage2DurationEditField.Enable = "off";
                    app.BlueStage2FreqEditField.Enable = "off";
                    app.BlueStage2DutyRatioEditField.Enable = "off";
                elseif app.blue_stage2_switch == "On"
                    app.BlueStage2DurationEditField.Enable = "on";
                    app.BlueStage2FreqEditField.Enable = "on";
                    app.BlueStage2DutyRatioEditField.Enable = "on";
                end
                
                if app.blue_stage3_switch == "Off"
                    app.BlueStage3DurationEditField.Enable = "off";
                    app.BlueStage3FreqEditField.Enable = "off";
                    app.BlueStage3DutyRatioEditField.Enable = "off";
                elseif app.blue_stage3_switch == "On"
                    app.BlueStage3DurationEditField.Enable = "on";
                    app.BlueStage3FreqEditField.Enable = "on";
                    app.BlueStage3DutyRatioEditField.Enable = "on";
                end
                
                if app.blue_stage4_switch == "Off"
                    app.BlueStage4DurationEditField.Enable = "off";
                    app.BlueStage4FreqEditField.Enable = "off";
                    app.BlueStage4DutyRatioEditField.Enable = "off";
                elseif app.blue_stage4_switch == "On"
                    app.BlueStage4DurationEditField.Enable = "on";
                    app.BlueStage4FreqEditField.Enable = "on";
                    app.BlueStage4DutyRatioEditField.Enable = "on";
                end
                
                if app.uv_stage1_switch == "Off"
                    app.UVStage1DurationEditField.Enable = "off";
                    app.UVStage1FreqEditField.Enable = "off";
                    app.UVStage1DutyRatioEditField.Enable = "off";
                elseif app.uv_stage1_switch == "On"
                    app.UVStage1DurationEditField.Enable = "on";
                    app.UVStage1FreqEditField.Enable = "on";
                    app.UVStage1DutyRatioEditField.Enable = "on";
                end
                
                if app.uv_stage2_switch == "Off"
                    app.UVStage2DurationEditField.Enable = "off";
                    app.UVStage2FreqEditField.Enable = "off";
                    app.UVStage2DutyRatioEditField.Enable = "off";
                elseif app.uv_stage2_switch == "On"
                    app.UVStage2DurationEditField.Enable = "on";
                    app.UVStage2FreqEditField.Enable = "on";
                    app.UVStage2DutyRatioEditField.Enable = "on";
                end
                
                if app.uv_stage3_switch == "Off"
                    app.UVStage3DurationEditField.Enable = "off";
                    app.UVStage3FreqEditField.Enable = "off";
                    app.UVStage3DutyRatioEditField.Enable = "off";
                elseif app.uv_stage3_switch == "On"
                    app.UVStage3DurationEditField.Enable = "on";
                    app.UVStage3FreqEditField.Enable = "on";
                    app.UVStage3DutyRatioEditField.Enable = "on";
                end
                
                if app.uv_stage4_switch == "Off"
                    app.UVStage4DurationEditField.Enable = "off";
                    app.UVStage4FreqEditField.Enable = "off";
                    app.UVStage4DutyRatioEditField.Enable = "off";
                elseif app.uv_stage4_switch == "On"
                    app.UVStage4DurationEditField.Enable = "on";
                    app.UVStage4FreqEditField.Enable = "on";
                    app.UVStage4DutyRatioEditField.Enable = "on";
                end
            end
        end

        % Menu selected function: SaveConfigMenu
        function SaveConfigMenuSelected(app, event)
            config.pre_period = str2double(app.PrePeriodEditField.Value); % unit: s

            config.stimulation_period = str2double(app.StimulationPeriodEditField.Value); % unit: s
            config.visualstimuli_switch = app.VisualStimuliSwitch.Value; % 'On' or 'Off'
            config.photometry_switch = app.PhotometrySwitch.Value; % 'On' or 'Off'
            config.camera_switch = app.CameraSwitch.Value; % 'On' or 'Off'
            config.rotary_encoder_switch = app.RotaryEncoderSwitch.Value; % 'On' or 'Off'
            
            config.red_start_time = str2double(app.RedStartTimeEditField.Value); % unit: s
            config.red_left_time = str2double(app.RedLeftTimeEditField.Value);
            config.red_stage1_switch = app.RedStage1Switch.Value; % 'On' or 'Off'
            config.red_stage1_duration = str2double(app.RedStage1DurationEditField.Value); % unit: s
            config.red_stage1_freq = str2double(app.RedStage1FreqEditField.Value); % unit:Hz
            config.red_stage1_duty_ratio = str2double(app.RedStage1DutyRatioEditField.Value); % unit:%
            config.red_stage2_switch = app.RedStage2Switch.Value;
            config.red_stage2_duration = str2double(app.RedStage2DurationEditField.Value);
            config.red_stage2_freq = str2double(app.RedStage2FreqEditField.Value);
            config.red_stage2_duty_ratio = str2double(app.RedStage2DutyRatioEditField.Value);
            config.red_stage3_switch = app.RedStage3Switch.Value;
            config.red_stage3_duration = str2double(app.RedStage3DurationEditField.Value);
            config.red_stage3_freq = str2double(app.RedStage3FreqEditField.Value);
            config.red_stage3_duty_ratio = str2double(app.RedStage3DutyRatioEditField.Value);
            config.red_stage4_switch = app.RedStage4Switch.Value;
            config.red_stage4_duration = str2double(app.RedStage4DurationEditField.Value);
            config.red_stage4_freq = str2double(app.RedStage4FreqEditField.Value);
            config.red_stage4_duty_ratio = str2double(app.RedStage4DutyRatioEditField.Value);
            
            config.blue_start_time = str2double(app.BlueStartTimeEditField.Value);
            config.blue_left_time = str2double(app.BlueLeftTimeEditField.Value);
            config.blue_stage1_switch = app.BlueStage1Switch.Value;
            config.blue_stage1_duration = str2double(app.BlueStage1DurationEditField.Value);
            config.blue_stage1_freq = str2double(app.BlueStage1FreqEditField.Value);
            config.blue_stage1_duty_ratio = str2double(app.BlueStage1DutyRatioEditField.Value);
            config.blue_stage2_switch = app.BlueStage2Switch.Value;
            config.blue_stage2_duration = str2double(app.BlueStage2DurationEditField.Value);
            config.blue_stage2_freq = str2double(app.BlueStage2FreqEditField.Value);
            config.blue_stage2_duty_ratio = str2double(app.BlueStage2DutyRatioEditField.Value);
            config.blue_stage3_switch = app.BlueStage3Switch.Value;
            config.blue_stage3_duration = str2double(app.BlueStage3DurationEditField.Value);
            config.blue_stage3_freq = str2double(app.BlueStage3FreqEditField.Value);
            config.blue_stage3_duty_ratio = str2double(app.BlueStage3DutyRatioEditField.Value);
            config.blue_stage4_switch = app.BlueStage4Switch.Value;
            config.blue_stage4_duration = str2double(app.BlueStage4DurationEditField.Value);
            config.blue_stage4_freq = str2double(app.BlueStage4FreqEditField.Value);
            config.blue_stage4_duty_ratio = str2double(app.BlueStage4DutyRatioEditField.Value);
            
            config.uv_start_time = str2double(app.UVStartTimeEditField.Value);
            config.uv_left_time = str2double(app.UVLeftTimeEditField.Value);
            config.uv_stage1_switch = app.UVStage1Switch.Value;
            config.uv_stage1_duration = str2double(app.UVStage1DurationEditField.Value);
            config.uv_stage1_freq = str2double(app.UVStage1FreqEditField.Value);
            config.uv_stage1_duty_ratio = str2double(app.UVStage1DutyRatioEditField.Value);
            config.uv_stage2_switch = app.UVStage2Switch.Value;
            config.uv_stage2_duration = str2double(app.UVStage2DurationEditField.Value);
            config.uv_stage2_freq = str2double(app.UVStage2FreqEditField.Value);
            config.uv_stage2_duty_ratio = str2double(app.UVStage2DutyRatioEditField.Value);
            config.uv_stage3_switch = app.UVStage3Switch.Value;
            config.uv_stage3_duration = str2double(app.UVStage3DurationEditField.Value);
            config.uv_stage3_freq = str2double(app.UVStage3FreqEditField.Value);
            config.uv_stage3_duty_ratio = str2double(app.UVStage3DutyRatioEditField.Value);
            config.uv_stage4_switch = app.UVStage4Switch.Value;
            config.uv_stage4_duration = str2double(app.UVStage4DurationEditField.Value);
            config.uv_stage4_freq = str2double(app.UVStage4FreqEditField.Value);
            config.uv_stage4_duty_ratio = str2double(app.UVStage4DutyRatioEditField.Value);
            
            config.post_period = str2double(app.PostPeriodEditField.Value);

            % Open the file save dialog
            [fileName, filePath] = uiputfile('*.json', 'Save JSON File');
        
            % Check if the user selected a file name and path
            if fileName
                % Create the full file path
                fullFilePath = fullfile(filePath, fileName);
        
                % Save files using the specified file path
                jsonStr = jsonencode(config);
                fid = fopen(fullFilePath, 'w');
                fwrite(fid, jsonStr);
                fclose(fid);               
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            OptoPhotometry_control_help;
        end

        % Value changed function: PrePeriodEditField
        function PrePeriodEditFieldValueChanged(app, event)
            value = app.PrePeriodEditField.Value;
            app.pre_period = str2double(value);
        end

        % Value changed function: StimulationPeriodEditField
        function StimulationPeriodEditFieldValueChanged(app, event)
            value = app.StimulationPeriodEditField.Value;
            app.stimulation_period = str2double(value);
        end

        % Value changed function: VisualStimuliSwitch
        function VisualStimuliSwitchValueChanged(app, event)
            value = app.VisualStimuliSwitch.Value;
            app.visual_stimuli_switch = value;
        end

        % Value changed function: PhotometrySwitch
        function PhotometrySwitchValueChanged(app, event)
            value = app.PhotometrySwitch.Value;
            app.photometry_switch = value;
        end

        % Value changed function: CameraSwitch
        function CameraSwitchValueChanged(app, event)
            value = app.CameraSwitch.Value;
            app.camera_switch = value;
        end

        % Value changed function: RotaryEncoderSwitch
        function RotaryEncoderSwitchValueChanged(app, event)
            value = app.RotaryEncoderSwitch.Value;
            app.rotary_encoder_switch = value;
        end

        % Value changed function: RedStartTimeEditField
        function RedStartTimeEditFieldValueChanged(app, event)
            value = app.RedStartTimeEditField.Value;
            app.red_start_time = value;
        end

        % Value changed function: RedLeftTimeEditField
        function RedLeftTimeEditFieldValueChanged(app, event)
            value = app.RedLeftTimeEditField.Value;
            app.red_left_time = value;
        end

        % Value changed function: RedStage1Switch
        function RedStage1SwitchValueChanged(app, event)
            value = app.RedStage1Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.RedStage1DurationEditField.Enable = "off";
                app.RedStage1FreqEditField.Enable = "off";
                app.RedStage1DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.RedStage1DurationEditField.Enable = "on";
                app.RedStage1FreqEditField.Enable = "on";
                app.RedStage1DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: RedStage1DurationEditField
        function RedStage1DurationEditFieldValueChanged(app, event)
            value = app.RedStage1DurationEditField.Value;
            app.red_stage1_duration = str2double(value);
        end

        % Value changed function: RedStage1FreqEditField
        function RedStage1FreqEditFieldValueChanged(app, event)
            value = app.RedStage1FreqEditField.Value;
            app.red_stage1_freq = str2double(value);
        end

        % Value changed function: RedStage1DutyRatioEditField
        function RedStage1DutyRatioEditFieldValueChanged(app, event)
            value = app.RedStage1DutyRatioEditField.Value;
            app.red_stage1_duty_ratio = str2double(value);
        end

        % Value changed function: RedStage2Switch
        function RedStage2SwitchValueChanged(app, event)
            value = app.RedStage2Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.RedStage2DurationEditField.Enable = "off";
                app.RedStage2FreqEditField.Enable = "off";
                app.RedStage2DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.RedStage2DurationEditField.Enable = "on";
                app.RedStage2FreqEditField.Enable = "on";
                app.RedStage2DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: RedStage2DurationEditField
        function RedStage2DurationEditFieldValueChanged(app, event)
            value = app.RedStage2DurationEditField.Value;
            app.red_stage2_duration = str2double(value);
        end

        % Value changed function: RedStage2FreqEditField
        function RedStage2FreqEditFieldValueChanged(app, event)
            value = app.RedStage2FreqEditField.Value;
            app.red_stage2_freq = str2double(value);
        end

        % Value changed function: RedStage2DutyRatioEditField
        function RedStage2DutyRatioEditFieldValueChanged(app, event)
            value = app.RedStage2DutyRatioEditField.Value;
            app.red_stage2_duty_ratio = str2double(value);
        end

        % Value changed function: RedStage3Switch
        function RedStage3SwitchValueChanged(app, event)
            value = app.RedStage3Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.RedStage3DurationEditField.Enable = "off";
                app.RedStage3FreqEditField.Enable = "off";
                app.RedStage3DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.RedStage3DurationEditField.Enable = "on";
                app.RedStage3FreqEditField.Enable = "on";
                app.RedStage3DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: RedStage3DurationEditField
        function RedStage3DurationEditFieldValueChanged(app, event)
            value = app.RedStage3DurationEditField.Value;
            app.red_stage3_duration = str2double(value);
        end

        % Value changed function: RedStage3FreqEditField
        function RedStage3FreqEditFieldValueChanged(app, event)
            value = app.RedStage3FreqEditField.Value;
            app.red_stage3_freq = str2double(value);
        end

        % Value changed function: RedStage3DutyRatioEditField
        function RedStage3DutyRatioEditFieldValueChanged(app, event)
            value = app.RedStage3DutyRatioEditField.Value;
            app.red_stage3_duty_ratio = str2double(value);
        end

        % Value changed function: RedStage4Switch
        function RedStage4SwitchValueChanged(app, event)
            value = app.RedStage4Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.RedStage4DurationEditField.Enable = "off";
                app.RedStage4FreqEditField.Enable = "off";
                app.RedStage4DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.RedStage4DurationEditField.Enable = "on";
                app.RedStage4FreqEditField.Enable = "on";
                app.RedStage4DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: RedStage4DurationEditField
        function RedStage4DurationEditFieldValueChanged(app, event)
            value = app.RedStage4DurationEditField.Value;
            app.red_stage4_duration = str2double(value);
        end

        % Value changed function: RedStage4FreqEditField
        function RedStage4FreqEditFieldValueChanged(app, event)
            value = app.RedStage4FreqEditField.Value;
            app.red_stage4_freq = str2double(value);
        end

        % Value changed function: RedStage4DutyRatioEditField
        function RedStage4DutyRatioEditFieldValueChanged(app, event)
            value = app.RedStage4DutyRatioEditField.Value;
            app.red_stage4_duty_ratio = str2double(value);
        end

        % Value changed function: BlueStartTimeEditField
        function BlueStartTimeEditFieldValueChanged(app, event)
            value = app.BlueStartTimeEditField.Value;
            app.blue_start_time = value;
        end

        % Value changed function: BlueLeftTimeEditField
        function BlueLeftTimeEditFieldValueChanged(app, event)
            value = app.BlueLeftTimeEditField.Value;
            app.blue_left_time = value;
        end

        % Value changed function: BlueStage1Switch
        function BlueStage1SwitchValueChanged(app, event)
            value = app.BlueStage1Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.BlueStage1DurationEditField.Enable = "off";
                app.BlueStage1FreqEditField.Enable = "off";
                app.BlueStage1DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.BlueStage1DurationEditField.Enable = "on";
                app.BlueStage1FreqEditField.Enable = "on";
                app.BlueStage1DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: BlueStage1DurationEditField
        function BlueStage1DurationEditFieldValueChanged(app, event)
            value = app.BlueStage1DurationEditField.Value;
            app.blue_stage1_duration = str2double(value);
        end

        % Value changed function: BlueStage1FreqEditField
        function BlueStage1FreqEditFieldValueChanged(app, event)
            value = app.BlueStage1FreqEditField.Value;
            app.blue_stage1_freq = str2double(value);
        end

        % Value changed function: BlueStage1DutyRatioEditField
        function BlueStage1DutyRatioEditFieldValueChanged(app, event)
            value = app.BlueStage1DutyRatioEditField.Value;
            app.blue_stage1_duty_ratio = str2double(value);
        end

        % Value changed function: BlueStage2Switch
        function BlueStage2SwitchValueChanged(app, event)
            value = app.BlueStage2Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.BlueStage2DurationEditField.Enable = "off";
                app.BlueStage2FreqEditField.Enable = "off";
                app.BlueStage2DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.BlueStage2DurationEditField.Enable = "on";
                app.BlueStage2FreqEditField.Enable = "on";
                app.BlueStage2DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: BlueStage2DurationEditField
        function BlueStage2DurationEditFieldValueChanged(app, event)
            value = app.BlueStage2DurationEditField.Value;
            app.blue_stage2_duration = str2double(value);
        end

        % Value changed function: BlueStage2FreqEditField
        function BlueStage2FreqEditFieldValueChanged(app, event)
            value = app.BlueStage2FreqEditField.Value;
            app.blue_stage2_freq = str2double(value);
        end

        % Value changed function: BlueStage2DutyRatioEditField
        function BlueStage2DutyRatioEditFieldValueChanged(app, event)
            value = app.BlueStage2DutyRatioEditField.Value;
            app.blue_stage2_duty_ratio = str2double(value);
        end

        % Value changed function: BlueStage3Switch
        function BlueStage3SwitchValueChanged(app, event)
            value = app.BlueStage3Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.BlueStage3DurationEditField.Enable = "off";
                app.BlueStage3FreqEditField.Enable = "off";
                app.BlueStage3DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.BlueStage3DurationEditField.Enable = "on";
                app.BlueStage3FreqEditField.Enable = "on";
                app.BlueStage3DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: BlueStage3DurationEditField
        function BlueStage3DurationEditFieldValueChanged(app, event)
            value = app.BlueStage3DurationEditField.Value;
            app.blue_stage3_duration = str2double(value);
        end

        % Value changed function: BlueStage3FreqEditField
        function BlueStage3FreqEditFieldValueChanged(app, event)
            value = app.BlueStage3FreqEditField.Value;
            app.blue_stage3_freq = str2double(value);
        end

        % Value changed function: BlueStage3DutyRatioEditField
        function BlueStage3DutyRatioEditFieldValueChanged(app, event)
            value = app.BlueStage3DutyRatioEditField.Value;
            app.blue_stage3_duty_ratio = str2double(value);
        end

        % Value changed function: BlueStage4Switch
        function BlueStage4SwitchValueChanged(app, event)
            value = app.BlueStage4Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.BlueStage4DurationEditField.Enable = "off";
                app.BlueStage4FreqEditField.Enable = "off";
                app.BlueStage4DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.BlueStage4DurationEditField.Enable = "on";
                app.BlueStage4FreqEditField.Enable = "on";
                app.BlueStage4DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: BlueStage4DurationEditField
        function BlueStage4DurationEditFieldValueChanged(app, event)
            value = app.BlueStage4DurationEditField.Value;
            app.blue_stage4_duration = str2double(value);
        end

        % Value changed function: BlueStage4FreqEditField
        function BlueStage4FreqEditFieldValueChanged(app, event)
            value = app.BlueStage4FreqEditField.Value;
            app.blue_stage4_freq = str2double(value);
        end

        % Value changed function: BlueStage4DutyRatioEditField
        function BlueStage4DutyRatioEditFieldValueChanged(app, event)
            value = app.BlueStage4DutyRatioEditField.Value;
            app.blue_stage4_duty_ratio = str2double(value);
        end

        % Value changed function: UVStartTimeEditField
        function UVStartTimeEditFieldValueChanged(app, event)
            value = app.UVStartTimeEditField.Value;
            app.uv_start_time = value;
        end

        % Value changed function: UVLeftTimeEditField
        function UVLeftTimeEditFieldValueChanged(app, event)
            value = app.UVLeftTimeEditField.Value;
            app.uv_left_time = value;
        end

        % Value changed function: UVStage1Switch
        function UVStage1SwitchValueChanged(app, event)
            value = app.UVStage1Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.UVStage1DurationEditField.Enable = "off";
                app.UVStage1FreqEditField.Enable = "off";
                app.UVStage1DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.UVStage1DurationEditField.Enable = "on";
                app.UVStage1FreqEditField.Enable = "on";
                app.UVStage1DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: UVStage1DurationEditField
        function UVStage1DurationEditFieldValueChanged(app, event)
            value = app.UVStage1DurationEditField.Value;
            app.uv_stage1_duration = str2double(value);
        end

        % Value changed function: UVStage1FreqEditField
        function UVStage1FreqEditFieldValueChanged(app, event)
            value = app.UVStage1FreqEditField.Value;
            app.uv_stage1_freq = str2double(value);
        end

        % Value changed function: UVStage1DutyRatioEditField
        function UVStage1DutyRatioEditFieldValueChanged(app, event)
            value = app.UVStage1DutyRatioEditField.Value;
            app.uv_stage1_duty_ratio = str2double(value);
        end

        % Value changed function: UVStage2Switch
        function UVStage2SwitchValueChanged(app, event)
            value = app.UVStage2Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.UVStage2DurationEditField.Enable = "off";
                app.UVStage2FreqEditField.Enable = "off";
                app.UVStage2DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.UVStage2DurationEditField.Enable = "on";
                app.UVStage2FreqEditField.Enable = "on";
                app.UVStage2DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: UVStage2DurationEditField
        function UVStage2DurationEditFieldValueChanged(app, event)
            value = app.UVStage2DurationEditField.Value;
            app.uv_stage2_duration = str2double(value);
        end

        % Value changed function: UVStage2FreqEditField
        function UVStage2FreqEditFieldValueChanged(app, event)
            value = app.UVStage2FreqEditField.Value;
            app.uv_stage2_freq = str2double(value);
        end

        % Value changed function: UVStage2DutyRatioEditField
        function UVStage2DutyRatioEditFieldValueChanged(app, event)
            value = app.UVStage2DutyRatioEditField.Value;
            app.uv_stage2_duty_ratio = str2double(value);
        end

        % Value changed function: UVStage3Switch
        function UVStage3SwitchValueChanged(app, event)
            value = app.UVStage3Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.UVStage3DurationEditField.Enable = "off";
                app.UVStage3FreqEditField.Enable = "off";
                app.UVStage3DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.UVStage3DurationEditField.Enable = "on";
                app.UVStage3FreqEditField.Enable = "on";
                app.UVStage3DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: UVStage3DurationEditField
        function UVStage3DurationEditFieldValueChanged(app, event)
            value = app.UVStage3DurationEditField.Value;
            app.uv_stage3_duration = str2double(value);
        end

        % Value changed function: UVStage3FreqEditField
        function UVStage3FreqEditFieldValueChanged(app, event)
            value = app.UVStage3FreqEditField.Value;
            app.uv_stage3_freq = str2double(value);
        end

        % Value changed function: UVStage3DutyRatioEditField
        function UVStage3DutyRatioEditFieldValueChanged(app, event)
            value = app.UVStage3DutyRatioEditField.Value;
            app.uv_stage3_duty_ratio = str2double(value);
        end

        % Value changed function: UVStage4Switch
        function UVStage4SwitchValueChanged(app, event)
            value = app.UVStage4Switch.Value;
            if value == "Off" % value is actually char, but char can be compared with string
                app.UVStage4DurationEditField.Enable = "off";
                app.UVStage4FreqEditField.Enable = "off";
                app.UVStage4DutyRatioEditField.Enable = "off";
            elseif value == "On"
                app.UVStage4DurationEditField.Enable = "on";
                app.UVStage4FreqEditField.Enable = "on";
                app.UVStage4DutyRatioEditField.Enable = "on";
            end
        end

        % Value changed function: UVStage4DurationEditField
        function UVStage4DurationEditFieldValueChanged(app, event)
            value = app.UVStage4DurationEditField.Value;
            app.uv_stage4_duration = str2double(value);
        end

        % Value changed function: UVStage4FreqEditField
        function UVStage4FreqEditFieldValueChanged(app, event)
            value = app.UVStage4FreqEditField.Value;
            app.uv_stage4_freq = str2double(value);
        end

        % Value changed function: UVStage4DutyRatioEditField
        function UVStage4DutyRatioEditFieldValueChanged(app, event)
            value = app.UVStage4DutyRatioEditField.Value;
            app.uv_stage4_duty_ratio = str2double(value);
        end

        % Value changed function: PostPeriodEditField
        function PostPeriodEditFieldValueChanged(app, event)
            value = app.PostPeriodEditField.Value;
            app.post_period = str2double(value);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.StartButton.Enable = "off";
            disp_info = "Experiment will begin soon!";
            disp(disp_info);
            app.OutputTextArea.Value = [app.OutputTextArea.Value; disp_info];
            app.OutputTextArea.scroll('bottom');
            pause(1);
            OptoPhotometry_func_for_App(app);
            pause(1);
            disp_info = "All done!";
            disp(disp_info);
            app.OutputTextArea.Value = [app.OutputTextArea.Value; disp_info];
            app.OutputTextArea.scroll('bottom');
            app.StartButton.Enable = "on";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.OptoPhotometryControlLiangLabUIFigure = uifigure('Visible', 'off');
            app.OptoPhotometryControlLiangLabUIFigure.Name = 'OptoPhotometry Control @ Liang Lab';
            app.OptoPhotometryControlLiangLabUIFigure.Icon = fullfile(pathToMLAPP, 'brain_icon.jpg');
            app.ConfigMenu = uimenu(app.OptoPhotometryControlLiangLabUIFigure);
            app.ConfigMenu.Text = 'Config';
            app.LoadConfigMenu = uimenu(app.ConfigMenu);
            app.LoadConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadConfigMenuSelected, true);
            app.LoadConfigMenu.Text = 'Load Config';
            app.SaveConfigMenu = uimenu(app.ConfigMenu);
            app.SaveConfigMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveConfigMenuSelected, true);
            app.SaveConfigMenu.Text = 'Save Config';
            app.OutputTextArea = uitextarea(app.OptoPhotometryControlLiangLabUIFigure);
            app.OutputTextArea.Editable = 'off';
            app.StartButton = uibutton(app.OptoPhotometryControlLiangLabUIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Icon = fullfile(pathToMLAPP, 'mouse_icon.jpg');
            app.StartButton.Text = 'Start';
            app.unitsLabel_3 = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.unitsLabel_3.Text = 'unit: s';
            app.PostPeriodEditField = uieditfield(app.OptoPhotometryControlLiangLabUIFigure, 'text');
            app.PostPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @PostPeriodEditFieldValueChanged, true);
            app.PostPeriodEditField.Value = '10';
            app.StimulationPeriodPanel = uipanel(app.OptoPhotometryControlLiangLabUIFigure);
            app.StimulationPeriodPanel.Title = 'Stimulation Period';
            app.UVPanel = uipanel(app.StimulationPeriodPanel);
            app.UVPanel.Title = 'UV';
            app.StartTimeEditField_3Label = uilabel(app.UVPanel);
            app.StartTimeEditField_3Label.Text = 'Start Time';
            app.FreqEditField_23Label = uilabel(app.UVPanel);
            app.FreqEditField_23Label.Text = 'Freq';
            app.DutyRatioEditField_25Label = uilabel(app.UVPanel);
            app.DutyRatioEditField_25Label.Text = 'Duty Ratio';
            app.UVStage4DutyRatioEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage4DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage4DutyRatioEditFieldValueChanged, true);
            app.UVStage4DutyRatioEditField.Value = '0';
            app.UVStage4FreqEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage4FreqEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage4FreqEditFieldValueChanged, true);
            app.UVStage4FreqEditField.Value = '1';
            app.UVStage4DurationEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage4DurationEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage4DurationEditFieldValueChanged, true);
            app.UVStage4DurationEditField.Value = '23';
            app.UVStage4Switch = uiswitch(app.UVPanel, 'slider');
            app.UVStage4Switch.ValueChangedFcn = createCallbackFcn(app, @UVStage4SwitchValueChanged, true);
            app.UVStage3DutyRatioEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage3DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage3DutyRatioEditFieldValueChanged, true);
            app.UVStage3DutyRatioEditField.Value = '100';
            app.FreqEditField_24Label = uilabel(app.UVPanel);
            app.FreqEditField_24Label.Text = 'Freq';
            app.DutyRatioEditField_26Label = uilabel(app.UVPanel);
            app.DutyRatioEditField_26Label.Text = 'Duty Ratio';
            app.FreqEditField_25Label = uilabel(app.UVPanel);
            app.FreqEditField_25Label.Text = 'Freq';
            app.UVStage3FreqEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage3FreqEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage3FreqEditFieldValueChanged, true);
            app.UVStage3FreqEditField.Value = '1';
            app.UVStage3DurationEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage3DurationEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage3DurationEditFieldValueChanged, true);
            app.UVStage3DurationEditField.Value = '1';
            app.DutyRatioEditField_27Label = uilabel(app.UVPanel);
            app.DutyRatioEditField_27Label.Text = 'Duty Ratio';
            app.UVStage3Switch = uiswitch(app.UVPanel, 'slider');
            app.UVStage3Switch.ValueChangedFcn = createCallbackFcn(app, @UVStage3SwitchValueChanged, true);
            app.UVStage2DutyRatioEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage2DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage2DutyRatioEditFieldValueChanged, true);
            app.UVStage2DutyRatioEditField.Value = '100';
            app.UVStage2FreqEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage2FreqEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage2FreqEditFieldValueChanged, true);
            app.UVStage2FreqEditField.Value = '1';
            app.UVStage2DurationEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage2DurationEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage2DurationEditFieldValueChanged, true);
            app.UVStage2DurationEditField.Value = '4';
            app.FreqEditField_26Label = uilabel(app.UVPanel);
            app.FreqEditField_26Label.Text = 'Freq';
            app.DutyRatioEditField_28Label = uilabel(app.UVPanel);
            app.DutyRatioEditField_28Label.Text = 'Duty Ratio';
            app.Stage1Switch_7Label = uilabel(app.UVPanel);
            app.Stage1Switch_7Label.Text = 'Stage 1';
            app.Stage2Switch_7Label = uilabel(app.UVPanel);
            app.Stage2Switch_7Label.Text = 'Stage 2';
            app.UVStage2Switch = uiswitch(app.UVPanel, 'slider');
            app.UVStage2Switch.ValueChangedFcn = createCallbackFcn(app, @UVStage2SwitchValueChanged, true);
            app.UVStage1DutyRatioEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage1DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage1DutyRatioEditFieldValueChanged, true);
            app.UVStage1DutyRatioEditField.Value = '30';
            app.UVStage1FreqEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage1FreqEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage1FreqEditFieldValueChanged, true);
            app.UVStage1FreqEditField.Value = '50';
            app.UVStage1DurationEditField = uieditfield(app.UVPanel, 'text');
            app.UVStage1DurationEditField.ValueChangedFcn = createCallbackFcn(app, @UVStage1DurationEditFieldValueChanged, true);
            app.UVStage1DurationEditField.Value = '1';
            app.Stage3Switch_7Label = uilabel(app.UVPanel);
            app.Stage3Switch_7Label.Text = 'Stage 3';
            app.Stage4Switch_7Label = uilabel(app.UVPanel);
            app.Stage4Switch_7Label.Text = 'Stage 4';
            app.UVStage1Switch = uiswitch(app.UVPanel, 'slider');
            app.UVStage1Switch.ValueChangedFcn = createCallbackFcn(app, @UVStage1SwitchValueChanged, true);
            app.UVStage1Switch.Value = 'On';
            app.UVLeftTimeEditField = uieditfield(app.UVPanel, 'text');
            app.UVLeftTimeEditField.ValueChangedFcn = createCallbackFcn(app, @UVLeftTimeEditFieldValueChanged, true);
            app.UVLeftTimeEditField.Value = '0.01';
            app.UVStartTimeEditField = uieditfield(app.UVPanel, 'text');
            app.UVStartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @UVStartTimeEditFieldValueChanged, true);
            app.UVStartTimeEditField.Value = '0.01';
            app.DurationEditField_25Label = uilabel(app.UVPanel);
            app.DurationEditField_25Label.Text = 'Duration';
            app.DurationEditField_26Label = uilabel(app.UVPanel);
            app.DurationEditField_26Label.Text = 'Duration';
            app.DurationEditField_27Label = uilabel(app.UVPanel);
            app.DurationEditField_27Label.Text = 'Duration';
            app.DurationEditField_28Label = uilabel(app.UVPanel);
            app.DurationEditField_28Label.Text = 'Duration';
            app.StartTimeEditField_3Label_2 = uilabel(app.UVPanel);
            app.StartTimeEditField_3Label_2.Text = 'Left Time';
            app.BluePanel = uipanel(app.StimulationPeriodPanel);
            app.BluePanel.Title = 'Blue';
            app.StartTimeEditField_4Label = uilabel(app.BluePanel);
            app.StartTimeEditField_4Label.Text = 'Start Time';
            app.FreqEditField_27Label = uilabel(app.BluePanel);
            app.FreqEditField_27Label.Text = 'Freq';
            app.DutyRatioEditField_29Label = uilabel(app.BluePanel);
            app.DutyRatioEditField_29Label.Text = 'Duty Ratio';
            app.FreqEditField_28Label = uilabel(app.BluePanel);
            app.FreqEditField_28Label.Text = 'Freq';
            app.DutyRatioEditField_30Label = uilabel(app.BluePanel);
            app.DutyRatioEditField_30Label.Text = 'Duty Ratio';
            app.FreqEditField_29Label = uilabel(app.BluePanel);
            app.FreqEditField_29Label.Text = 'Freq';
            app.DutyRatioEditField_31Label = uilabel(app.BluePanel);
            app.DutyRatioEditField_31Label.Text = 'Duty Ratio';
            app.BlueStage4DutyRatioEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage4DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage4DutyRatioEditFieldValueChanged, true);
            app.BlueStage4DutyRatioEditField.Value = '0';
            app.FreqEditField_30Label = uilabel(app.BluePanel);
            app.FreqEditField_30Label.Text = 'Freq';
            app.BlueStage4FreqEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage4FreqEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage4FreqEditFieldValueChanged, true);
            app.BlueStage4FreqEditField.Value = '1';
            app.BlueStage4DurationEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage4DurationEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage4DurationEditFieldValueChanged, true);
            app.BlueStage4DurationEditField.Value = '23';
            app.DutyRatioEditField_32Label = uilabel(app.BluePanel);
            app.DutyRatioEditField_32Label.Text = 'Duty Ratio';
            app.BlueStage4Switch = uiswitch(app.BluePanel, 'slider');
            app.BlueStage4Switch.ValueChangedFcn = createCallbackFcn(app, @BlueStage4SwitchValueChanged, true);
            app.BlueStage3DutyRatioEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage3DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage3DutyRatioEditFieldValueChanged, true);
            app.BlueStage3DutyRatioEditField.Value = '100';
            app.BlueStage3FreqEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage3FreqEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage3FreqEditFieldValueChanged, true);
            app.BlueStage3FreqEditField.Value = '1';
            app.BlueStage3DurationEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage3DurationEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage3DurationEditFieldValueChanged, true);
            app.BlueStage3DurationEditField.Value = '1';
            app.BlueStage3Switch = uiswitch(app.BluePanel, 'slider');
            app.BlueStage3Switch.ValueChangedFcn = createCallbackFcn(app, @BlueStage3SwitchValueChanged, true);
            app.BlueStage2DutyRatioEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage2DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage2DutyRatioEditFieldValueChanged, true);
            app.BlueStage2DutyRatioEditField.Value = '100';
            app.BlueStage2FreqEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage2FreqEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage2FreqEditFieldValueChanged, true);
            app.BlueStage2FreqEditField.Value = '1';
            app.BlueStage2DurationEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage2DurationEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage2DurationEditFieldValueChanged, true);
            app.BlueStage2DurationEditField.Value = '4';
            app.Stage1Switch_8Label = uilabel(app.BluePanel);
            app.Stage1Switch_8Label.Text = 'Stage 1';
            app.Stage2Switch_8Label = uilabel(app.BluePanel);
            app.Stage2Switch_8Label.Text = 'Stage 2';
            app.BlueStage2Switch = uiswitch(app.BluePanel, 'slider');
            app.BlueStage2Switch.ValueChangedFcn = createCallbackFcn(app, @BlueStage2SwitchValueChanged, true);
            app.BlueStage1DutyRatioEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage1DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage1DutyRatioEditFieldValueChanged, true);
            app.BlueStage1DutyRatioEditField.Value = '30';
            app.BlueStage1FreqEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage1FreqEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage1FreqEditFieldValueChanged, true);
            app.BlueStage1FreqEditField.Value = '50';
            app.BlueStage1DurationEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStage1DurationEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStage1DurationEditFieldValueChanged, true);
            app.BlueStage1DurationEditField.Value = '1';
            app.Stage3Switch_8Label = uilabel(app.BluePanel);
            app.Stage3Switch_8Label.Text = 'Stage 3';
            app.BlueStage1Switch = uiswitch(app.BluePanel, 'slider');
            app.BlueStage1Switch.ValueChangedFcn = createCallbackFcn(app, @BlueStage1SwitchValueChanged, true);
            app.BlueStage1Switch.Value = 'On';
            app.StartTimeEditField_4Label_2 = uilabel(app.BluePanel);
            app.StartTimeEditField_4Label_2.Text = 'Left Time';
            app.BlueLeftTimeEditField = uieditfield(app.BluePanel, 'text');
            app.BlueLeftTimeEditField.ValueChangedFcn = createCallbackFcn(app, @BlueLeftTimeEditFieldValueChanged, true);
            app.BlueLeftTimeEditField.Value = '0';
            app.BlueStartTimeEditField = uieditfield(app.BluePanel, 'text');
            app.BlueStartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @BlueStartTimeEditFieldValueChanged, true);
            app.BlueStartTimeEditField.Value = '0';
            app.Stage4Switch_8Label = uilabel(app.BluePanel);
            app.Stage4Switch_8Label.Text = 'Stage 4';
            app.DurationEditField_29Label = uilabel(app.BluePanel);
            app.DurationEditField_29Label.Text = 'Duration';
            app.DurationEditField_30Label = uilabel(app.BluePanel);
            app.DurationEditField_30Label.Text = 'Duration';
            app.DurationEditField_31Label = uilabel(app.BluePanel);
            app.DurationEditField_31Label.Text = 'Duration';
            app.DurationEditField_32Label = uilabel(app.BluePanel);
            app.DurationEditField_32Label.Text = 'Duration';
            app.RedPanel = uipanel(app.StimulationPeriodPanel);
            app.RedPanel.Title = 'Red';
            app.StartTimeEditFieldLabel = uilabel(app.RedPanel);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.DutyRatioEditField_21Label = uilabel(app.RedPanel);
            app.DutyRatioEditField_21Label.Text = 'Duty Ratio';
            app.FreqEditFieldLabel = uilabel(app.RedPanel);
            app.FreqEditFieldLabel.Text = 'Freq';
            app.Stage1Switch_6Label = uilabel(app.RedPanel);
            app.Stage1Switch_6Label.Text = 'Stage 1';
            app.DutyRatioEditField_22Label = uilabel(app.RedPanel);
            app.DutyRatioEditField_22Label.Text = 'Duty Ratio';
            app.FreqEditField_20Label = uilabel(app.RedPanel);
            app.FreqEditField_20Label.Text = 'Freq';
            app.RedStage4DutyRatioEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage4DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage4DutyRatioEditFieldValueChanged, true);
            app.RedStage4DutyRatioEditField.Value = '0';
            app.RedStage4FreqEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage4FreqEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage4FreqEditFieldValueChanged, true);
            app.RedStage4FreqEditField.Value = '1';
            app.RedStage4DurationEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage4DurationEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage4DurationEditFieldValueChanged, true);
            app.RedStage4DurationEditField.Value = '23';
            app.RedStage4Switch = uiswitch(app.RedPanel, 'slider');
            app.RedStage4Switch.ValueChangedFcn = createCallbackFcn(app, @RedStage4SwitchValueChanged, true);
            app.RedStage3DutyRatioEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage3DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage3DutyRatioEditFieldValueChanged, true);
            app.RedStage3DutyRatioEditField.Value = '0';
            app.RedStage3FreqEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage3FreqEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage3FreqEditFieldValueChanged, true);
            app.RedStage3FreqEditField.Value = '1';
            app.RedStage3DurationEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage3DurationEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage3DurationEditFieldValueChanged, true);
            app.RedStage3DurationEditField.Value = '1';
            app.RedStage3Switch = uiswitch(app.RedPanel, 'slider');
            app.RedStage3Switch.ValueChangedFcn = createCallbackFcn(app, @RedStage3SwitchValueChanged, true);
            app.RedStage2DutyRatioEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage2DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage2DutyRatioEditFieldValueChanged, true);
            app.RedStage2DutyRatioEditField.Value = '0';
            app.Stage2Switch_6Label = uilabel(app.RedPanel);
            app.Stage2Switch_6Label.Text = 'Stage 2';
            app.RedStage2FreqEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage2FreqEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage2FreqEditFieldValueChanged, true);
            app.RedStage2FreqEditField.Value = '20';
            app.RedStage2DurationEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage2DurationEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage2DurationEditFieldValueChanged, true);
            app.RedStage2DurationEditField.Value = '6';
            app.RedStage2Switch = uiswitch(app.RedPanel, 'slider');
            app.RedStage2Switch.ValueChangedFcn = createCallbackFcn(app, @RedStage2SwitchValueChanged, true);
            app.RedStage2Switch.Value = 'On';
            app.RedStage1DutyRatioEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage1DutyRatioEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage1DutyRatioEditFieldValueChanged, true);
            app.RedStage1DutyRatioEditField.Value = '20';
            app.RedStage1FreqEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage1FreqEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage1FreqEditFieldValueChanged, true);
            app.RedStage1FreqEditField.Value = '20';
            app.DutyRatioEditField_23Label = uilabel(app.RedPanel);
            app.DutyRatioEditField_23Label.Text = 'Duty Ratio';
            app.RedStage1DurationEditField = uieditfield(app.RedPanel, 'text');
            app.RedStage1DurationEditField.ValueChangedFcn = createCallbackFcn(app, @RedStage1DurationEditFieldValueChanged, true);
            app.RedStage1DurationEditField.Value = '6';
            app.RedStage1Switch = uiswitch(app.RedPanel, 'slider');
            app.RedStage1Switch.ValueChangedFcn = createCallbackFcn(app, @RedStage1SwitchValueChanged, true);
            app.RedStage1Switch.Value = 'On';
            app.LeftTimeLabel = uilabel(app.RedPanel);
            app.LeftTimeLabel.Text = 'Left Time';
            app.RedLeftTimeEditField = uieditfield(app.RedPanel, 'text');
            app.RedLeftTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RedLeftTimeEditFieldValueChanged, true);
            app.RedLeftTimeEditField.Value = '2';
            app.FreqEditField_21Label = uilabel(app.RedPanel);
            app.FreqEditField_21Label.Text = 'Freq';
            app.Stage3Switch_6Label = uilabel(app.RedPanel);
            app.Stage3Switch_6Label.Text = 'Stage 3';
            app.DutyRatioEditField_24Label = uilabel(app.RedPanel);
            app.DutyRatioEditField_24Label.Text = 'Duty Ratio';
            app.FreqEditField_22Label = uilabel(app.RedPanel);
            app.FreqEditField_22Label.Text = 'Freq';
            app.Stage4Switch_6Label = uilabel(app.RedPanel);
            app.Stage4Switch_6Label.Text = 'Stage 4';
            app.RedStartTimeEditField = uieditfield(app.RedPanel, 'text');
            app.RedStartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @RedStartTimeEditFieldValueChanged, true);
            app.RedStartTimeEditField.Value = '2';
            app.DurationEditField_21Label = uilabel(app.RedPanel);
            app.DurationEditField_21Label.Text = 'Duration';
            app.DurationEditField_22Label = uilabel(app.RedPanel);
            app.DurationEditField_22Label.Text = 'Duration';
            app.DurationEditField_23Label = uilabel(app.RedPanel);
            app.DurationEditField_23Label.Text = 'Duration';
            app.DurationEditField_24Label = uilabel(app.RedPanel);
            app.DurationEditField_24Label.Text = 'Duration';
            app.DutyRatiounitLabel = uilabel(app.StimulationPeriodPanel);
            app.DutyRatiounitLabel.Text = 'Duty Ratio unit: %';
            app.FrequnitHzLabel = uilabel(app.StimulationPeriodPanel);
            app.FrequnitHzLabel.Text = 'Freq unit: Hz';
            app.StartTimeDurationunitsLabel = uilabel(app.StimulationPeriodPanel);
            app.StartTimeDurationunitsLabel.Text = 'Start Time/Duration unit: s';
            app.PostPeriodEditField_2Label = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.PostPeriodEditField_2Label.Text = 'Post-Period';
            app.RotaryEncoderSwitch = uiswitch(app.OptoPhotometryControlLiangLabUIFigure, 'slider');
            app.RotaryEncoderSwitch.ValueChangedFcn = createCallbackFcn(app, @RotaryEncoderSwitchValueChanged, true);
            app.RotaryEncoderSwitch.Value = 'On';
            app.CameraSwitch = uiswitch(app.OptoPhotometryControlLiangLabUIFigure, 'slider');
            app.CameraSwitch.ValueChangedFcn = createCallbackFcn(app, @CameraSwitchValueChanged, true);
            app.CameraSwitch.Value = 'On';
            app.PhotometrySwitch = uiswitch(app.OptoPhotometryControlLiangLabUIFigure, 'slider');
            app.PhotometrySwitch.ValueChangedFcn = createCallbackFcn(app, @PhotometrySwitchValueChanged, true);
            app.PhotometrySwitch.Value = 'On';
            app.VisualStimuliSwitch = uiswitch(app.OptoPhotometryControlLiangLabUIFigure, 'slider');
            app.VisualStimuliSwitch.ValueChangedFcn = createCallbackFcn(app, @VisualStimuliSwitchValueChanged, true);
            app.VisualStimuliSwitch.Value = 'On';
            app.unitsLabel_2 = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.unitsLabel_2.Text = 'unit: s';
            app.StimulationPeriodEditField = uieditfield(app.OptoPhotometryControlLiangLabUIFigure, 'text');
            app.StimulationPeriodEditField.ValueChangedFcn = createCallbackFcn(app, @StimulationPeriodEditFieldValueChanged, true);
            app.StimulationPeriodEditField.Value = '24';
            app.unitsLabel_1 = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.unitsLabel_1.Text = 'unit: s';
            app.PrePeriodEditField = uieditfield(app.OptoPhotometryControlLiangLabUIFigure, 'text');
            app.PrePeriodEditField.ValueChangedFcn = createCallbackFcn(app, @PrePeriodEditFieldValueChanged, true);
            app.PrePeriodEditField.Value = '10';
            app.PrePeriodEditFieldLabel = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.PrePeriodEditFieldLabel.Text = 'Pre-Period';
            app.OutputTextAreaLabel = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.OutputTextAreaLabel.Text = 'Output';
            app.StimulationPeriodEditFieldLabel = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.StimulationPeriodEditFieldLabel.Text = 'Stimulation Period';
            app.PhotometrySwitchLabel = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.PhotometrySwitchLabel.Text = 'Photometry';
            app.CameraSwitchLabel = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.CameraSwitchLabel.Text = 'Camera';
            app.RotaryEncoderSwitchLabel = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.RotaryEncoderSwitchLabel.Text = 'Rotary Encoder';
            app.HelpButton = uibutton(app.OptoPhotometryControlLiangLabUIFigure, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.VisualStimuliSwitchLabel = uilabel(app.OptoPhotometryControlLiangLabUIFigure);
            app.VisualStimuliSwitchLabel.Text = 'Visual Stimuli';
            % Show the figure after all components are created
            app.OptoPhotometryControlLiangLabUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OptoPhotometry_control_v6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OptoPhotometryControlLiangLabUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OptoPhotometryControlLiangLabUIFigure)
        end
    end
end